classdef Continue_Cancel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ContinueButton  matlab.ui.control.Button
        CancelButton    matlab.ui.control.Button
    end


    properties (Access = private)
         % Description
    end

    properties (Access = public)
        confirm = false; % Description
        sethome = false;
        cancel = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SetSoftHomeButtonPushed(app, event)
            app.sethome = true;
            pause(0.2)  %pause and reset to false enables user to set softhome multiple times. 
            app.sethome = false;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.confirm = true;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.cancel = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continue_Cancel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end