classdef FUS_Hydrophone_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        HydrophoneCalibrationValueEditField  matlab.ui.control.NumericEditField
        HydrophoneCalibrationValueEditFieldLabel  matlab.ui.control.Label
        VMPaLabel                       matlab.ui.control.Label
        NewOriginButton                 matlab.ui.control.Button
        SoftHomePositionButtonGroup     matlab.ui.container.ButtonGroup
        FocalPointButton                matlab.ui.control.RadioButton
        ScanCornerButton                matlab.ui.control.RadioButton
        RecoverButton                   matlab.ui.control.Button
        SaveWaveinMatrixSwitch          matlab.ui.control.Switch
        SaveWaveinMatrixSwitchLabel     matlab.ui.control.Label
        FWHMSwitch                      matlab.ui.control.Switch
        FWHMSwitchLabel                 matlab.ui.control.Label
        CenterButton                    matlab.ui.control.Button
        SetSoftHomeButton               matlab.ui.control.Button
        SoftHomePanel                   matlab.ui.container.Panel
        XEditField_softhome             matlab.ui.control.NumericEditField
        XEditFieldLabel_4               matlab.ui.control.Label
        ZEditField_softhome             matlab.ui.control.NumericEditField
        ZEditFieldLabel_4               matlab.ui.control.Label
        YEditField_softhome             matlab.ui.control.NumericEditField
        YEditFieldLabel_4               matlab.ui.control.Label
        ScanDimensionsPanel             matlab.ui.container.Panel
        XEditField_dim                  matlab.ui.control.NumericEditField
        XEditFieldLabel_3               matlab.ui.control.Label
        ZEditField_dim                  matlab.ui.control.NumericEditField
        ZEditFieldLabel_3               matlab.ui.control.Label
        YEditField_dim                  matlab.ui.control.NumericEditField
        YEditFieldLabel_3               matlab.ui.control.Label
        MovePanel                       matlab.ui.container.Panel
        ZButton                         matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        YButton_2                       matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        XEditField_move                 matlab.ui.control.NumericEditField
        XEditFieldLabel_2               matlab.ui.control.Label
        ZEditField_move                 matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        YEditField_move                 matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        LimitPanel                      matlab.ui.container.Panel
        XEditField_lim                  matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        ZEditField_lim                  matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        YEditField_lim                  matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        PositionPanel                   matlab.ui.container.Panel
        XEditField_pos                  matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ZEditField_pos                  matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField_pos                  matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        PauseButton                     matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        ConnectionsPanel                matlab.ui.container.Panel
        Lamp3                           matlab.ui.control.Lamp
        OscilloscopeButton              matlab.ui.control.Button
        Lamp2                           matlab.ui.control.Lamp
        GeneratorButton                 matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ArduinoButton                   matlab.ui.control.Button
        RunScanButton                   matlab.ui.control.Button
        SaveAsDropDown                  matlab.ui.control.DropDown
        SaveAsDropDownLabel             matlab.ui.control.Label
        InforecordedinRead_MetxtPanel   matlab.ui.container.Panel
        TransducerModelEditField        matlab.ui.control.EditField
        TransducerModelEditFieldLabel   matlab.ui.control.Label
        HydrophoneModelEditField        matlab.ui.control.EditField
        HydrophoneModelEditFieldLabel   matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        DateEditField                   matlab.ui.control.EditField
        DateEditFieldLabel              matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        HardHomeButton                  matlab.ui.control.Button
        ResolutionmmEditField           matlab.ui.control.NumericEditField
        ResolutionmmEditFieldLabel      matlab.ui.control.Label
        ProgramDropDown                 matlab.ui.control.DropDown
        ProgramDropDownLabel            matlab.ui.control.Label
        FunctionGeneratorTab            matlab.ui.container.Tab
        ResetGeneratorButton            matlab.ui.control.Button
        LoadImpedanceOhmEditField       matlab.ui.control.NumericEditField
        LoadImpedanceOhmEditFieldLabel  matlab.ui.control.Label
        IntervalmsEditField             matlab.ui.control.NumericEditField
        IntervalmsEditFieldLabel        matlab.ui.control.Label
        TrigDelaymsEditField            matlab.ui.control.NumericEditField
        TrigDelaymsEditFieldLabel       matlab.ui.control.Label
        NumCyclesEditField              matlab.ui.control.NumericEditField
        NumCyclesEditFieldLabel         matlab.ui.control.Label
        RunModeDropDown                 matlab.ui.control.DropDown
        RunModeDropDownLabel            matlab.ui.control.Label
        OffsetVEditField                matlab.ui.control.NumericEditField
        OffsetVEditField_2Label         matlab.ui.control.Label
        PhaseDegEditField               matlab.ui.control.NumericEditField
        PhaseDegEditField_2Label        matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        AmplitudeVEditField             matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel        matlab.ui.control.Label
        FunctionDropDown                matlab.ui.control.DropDown
        FunctionDropDownLabel           matlab.ui.control.Label
        OscilloscopeTab                 matlab.ui.container.Tab
        SignaltoNoiseCalculatorPanel    matlab.ui.container.Panel
        SignaltoNoiseRatioEditField     matlab.ui.control.NumericEditField
        SignaltoNoiseRatioEditFieldLabel  matlab.ui.control.Label
        AverageSignalmVEditField        matlab.ui.control.NumericEditField
        AverageSignalmVLabel            matlab.ui.control.Label
        AverageNoisemVEditField         matlab.ui.control.NumericEditField
        AverageNoisemVLabel             matlab.ui.control.Label
        ComputeButton_2                 matlab.ui.control.Button
        ArrivalTimeCalculatorPanel      matlab.ui.container.Panel
        ArrivalTimeusEditField          matlab.ui.control.NumericEditField
        ArrivalTimeusLabel              matlab.ui.control.Label
        SpeedofSoundmmsEditField        matlab.ui.control.NumericEditField
        SpeedofSoundmmsLabel            matlab.ui.control.Label
        RadiusofCurvaturemmEditField    matlab.ui.control.NumericEditField
        RadiusofCurvaturemmLabel        matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        HorizontalPosition              matlab.ui.control.EditField
        HorizontalPositionsEditFieldLabel  matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        ResetScopeButton                matlab.ui.control.Button
        VerticalScaleVEditField         matlab.ui.control.EditField
        VerticalScaleVEditFieldLabel    matlab.ui.control.Label
        SlopeDropDown                   matlab.ui.control.DropDown
        SlopeDropDownLabel              matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        TrigCoupleDropDown              matlab.ui.control.DropDown
        TrigCoupleDropDownLabel         matlab.ui.control.Label
        HorizontalScalesEditField       matlab.ui.control.EditField
        HorizontalScalesEditFieldLabel  matlab.ui.control.Label
        AverageDropDown                 matlab.ui.control.DropDown
        AverageDropDownLabel            matlab.ui.control.Label
        AcquireModeDropDown             matlab.ui.control.DropDown
        AcquireModeDropDownLabel        matlab.ui.control.Label
        AttenuationDropDown             matlab.ui.control.DropDown
        AttenuationDropDownLabel        matlab.ui.control.Label
        TrigSourceDropDown              matlab.ui.control.DropDown
        TrigSourceDropDownLabel         matlab.ui.control.Label
        CouplingDropDown                matlab.ui.control.DropDown
        CouplingDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        % device objects
        
        Scope % visa scope object 
        MyFgen % visa generator object
        arduino % serial object
        
        ScopeAddress = 'USB::0x0699::0x03A0::C011326::INSTR';
%         GenAddress = 'USB::0x0699::0x034A::C020626::INSTR';
        GenAddress = 'USB::0x0699::0x0356::C014397::INSTR'; %New AFG
        % 31000 Series
        ArduinoPort = 'COM3';
        
        startMarker = '<'; %start and end markers for Arduino communication
        endMarker = '>';
        
        %scan dimensions
        mmperstep = 0.01; % lead/200 steps
        Xvoxels        % dimension of scan in voxels
        Yvoxels
        Zvoxels  
        stepspervoxel       % motor steps per voxel
        realresolution     % the voxel size in mm after rounding to ensure whole motor steps
     
        %aquiring and saving data
        
        zerom        % preallocated matrix for data.
        Maxmat % Maximum image matrix
        Minmat % Minimum image matrix
        path % save pathway determined by uiputfile in program button callback
        wave % waveform data
        maxV % Maximum Voltage
        minV % Minimum Voltage
        yOffset % scope offset
        fdata % handle for writing data to csv
        verticalScale % vertical scale for binary conversion
        voltperdiv % voltage per division
        posvoltdisp % positive voltage scale on display (4 * voltperdiv)
        noiseratio % Determines voltage scaling
        fid % handle of file for CSV data
        hydroPress_cal %calbibration value to convert V to MPa according to hydrophone frequency  
        
        
        lastx = -1; 
        lasty = -1;
        lastz = -1; 
        breakloop = false; % Used to cancel scans
        trigdistance   % distance [mm] from source  
        trigdelay      % time[units?] 
      
    end
    
    methods (Access = private)
        
                
        function closeApp_Callback(app, ~, event)
            %closeApp_Callback Executes after "Close Confirm" dialog window
            %  "Close Confirm" dialog window is launched from AppCloseRequest.
            
            switch event.SelectedOption
                case 'OK'
                    
                    %Disable Generator Output
                    try  
                    fopen(app.MyFgen);
                    fprintf(app.MyFgen,'OUTPUT OFF'); % turn off channel 1 output
                    fclose(app.MyFgen);
                    catch
                    end
                    
                    
                    clear app.Scope
                    clear app.MyFgen
                    clear app.arduino
                    
                    
                    try
                    fclose(app.fdata);
                    fclose(app.fid);
                    catch
                        %surpresses warning if files not open
                    end
                 
                    
                    instrreset   
                    
                    delete(app)
                    
                case 'Cancel'
                    % Continue
            end
        end
        
        
        function ReadMeGenerateFunc(app)
            %% Function Name: ReadMeGenerateFunc
            % Description: Records the scan parameters in a text file 
            % File location selected by uiputfile in "RunProgramButtonPushed" callback
            %
            % Assumptions: File location does not contain a Read_Me.txt already
            % Inputs:
            %   app
            %
            % Outputs:
            %   Read_Me.txt in selected path
            %
            % Last Edited by: Sam Clinard
            % Jun, 6 2020
            % ___________________
            
            
            pathname = strcat(app.path, '\Read_Me.txt');
            app.fid = fopen(pathname, 'wt');
            fprintf(app.fid, 'This is a read me containing acquisition parameters of an acoustic intensity measurement. \n\n');
            
            fprintf(app.fid, 'Data from aquistion \n');
            app.DateEditField.Value = datestr(now,'mm-dd-yyyy HH-MM');
            fprintf(app.fid, 'Date: %s \n', app.DateEditField.Value);
            fprintf(app.fid, 'File Path: %s \n', app.path);
            fprintf(app.fid, 'Temperature: %s *c \n', app.TemperatureCEditField.Value);
            fprintf(app.fid, 'Hydrophone Model: %s \n', app.HydrophoneModelEditField.Value);
            fprintf(app.fid, 'Tranducer Model: %s \n\n', app.TransducerModelEditField.Value);
           % fprintf(app.fid, 'Trig Delay Calibration distance from transducer to hydrophone [in]: %f \n', app.TrigDelayCalibrationDistancemmEditField.Value);
            fprintf(app.fid, 'Comments: ');
            fprintf(app.fid, '%s \n\n', app.CommentsTextArea.Value{:});
            
            
            fprintf(app.fid, 'Function Generator parameters \n');
            fprintf(app.fid, 'Function Type: %s \n', app.FunctionDropDown.Value);
            fprintf(app.fid, 'Function Mode: %s \n', app.RunModeDropDown.Value);
            fprintf(app.fid, 'Amplitude [v]: %f \n', app.AmplitudeVEditField.Value);
            fprintf(app.fid, 'Frequency [MHz]: %f \n', app.FrequencyHzEditField.Value);
            fprintf(app.fid, 'Phase [Deg]: %f \n', app.PhaseDegEditField.Value);
            fprintf(app.fid, 'Offset [V]: %f \n', app.OffsetVEditField.Value);
            fprintf(app.fid, 'Load Impedance [Ohm] (Changes function generator display to account for impedance): %f \n', app.LoadImpedanceOhmEditField.Value);
            if strcmp(app.FunctionDropDown.Value, 'Burst')
                fprintf(app.fid, 'Number of Cycles: %f \n', app.NumCylesEditField.Value);
                fprintf(app.fid, 'Interval [ms]: %f \n', app.IntervalmsEditField.Value);
            end
            
            fprintf(app.fid,'\n Program Settings \n');
            fprintf(app.fid, 'Program: %s \n', app.ProgramDropDown.Value);
            %undefined function 'fprintf' for input arguments of type double
            %fprinf(app.fid, 'Soft Home Location: x-%s, y-%s, z-%s', (app.XEditField_softhome.Value), (app.YEditField_softhome.Value), (app.ZEditField_softhome.Value));
           % fprintf(app.fid, 'Dimensions [mm]: x-%s, y-%s, z-%s', (app.XeditField_dim.Value), (app.YeditField_dim.Value), (app.ZEditField_dim.Value));
            fprintf(app.fid, 'Resolution [mm]: %f \n', app.ResolutionmmEditField.Value);
            fprintf(app.fid, 'Step = 0.01 mm \n');
            
            
            fprintf(app.fid, 'Oscilloscope Settings \n');
            fprintf(app.fid, 'Coupling %s \n', app.CouplingDropDown.Value);
            fprintf(app.fid, 'Vertical scale %s [V] \n', app.VerticalScaleVEditField.Value);
            fprintf(app.fid, 'Horizontal Scale %s [s] \n', app.HorizontalScalesEditField.Value);
            fprintf(app.fid, 'Trigger Source %s \n', app.TrigSourceDropDown.Value);
            fprintf(app.fid, 'Trigger Coupling %s \n', app.TrigCoupleDropDown.Value);
            fprintf(app.fid, 'Trigger Slope: %s \n', app.SlopeDropDown.Value);
            fprintf(app.fid, 'Attenuation %s \n', app.AttenuationDropDown.Value);
            fprintf(app.fid, "Average Mode %s \n", app.AcquireModeDropDown.Value);
            fprintf(app.fid, 'Number of Averages %s \n', app.AverageDropDown.Value);
            
           
            fclose(app.fid);
        end
        
        function  ZeroMatrix(app) 
            %% Function Name: ZeroMatrix
            % Description: Preallocates a matlab matrix to store data.
            % Size of matrix is determined by "RunProgramButtonPushed" callback.
            %
            % Assumptions: None
            %
            % Inputs:
            %   app
            %
            % Outputs:
            %   app.zerom property
            %
            %Last Edited: Sam Clinard
            % Jun 6, 2020
            % ___________________
        
            
            program = app.ProgramDropDown.Value;
            
            waveswitch = app.SaveWaveinMatrixSwitch.Value;
            
            if strcmp(waveswitch, 'On')
               n1dim = 2502;
            else
               n1dim = 2;
            end
            
            %the order of Xvoxels, Yvoxels, Zvoxels needs to match the program it's refering to. 
            switch program
                case '3D'                  
                    app.zerom = zeros(app.Zvoxels, app.Xvoxels, app.Yvoxels, n1dim);
                    
                case '2DXY'
                    app.zerom = zeros(app.Yvoxels, app.Xvoxels, n1dim);
                    
                case '2DXZ'                   
                    app.zerom = zeros(app.Zvoxels, app.Xvoxels, n1dim);
                    
                case '2DYZ'                    
                    app.zerom = zeros(app.Zvoxels, app.Yvoxels, n1dim);
                    
                case '1DX'
                    app.zerom = zeros(app.Xvoxels, n1dim);
                    
                case '1DY'
                    app.zerom = zeros(app.Yvoxels, n1dim);
                    
                case '1DZ'
                    app.zerom = zeros(app.Zvoxels, n1dim);
                    
            end
            
        end
        
        function [response] = GoHome(app)
            %% Function Name: GoHome
            % Description: Moves the motors to the home position
            % X and Y home position determined by limit switches on motor side. 
            % Z home position determined by limit switch at top of z axis. 
            % Limit switches may be moved to adjust home position
            %
            % Assumptions: None
            % Inputs:
            %   app
            %
            % Outputs:
            %   None
            %
            %Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            %arduino returns "Motors homed" when finished
            %while app.StopButton.Value ~= 1
            app.arduino.Timeout = 180;
            disp(app.arduino)
            response = writeread(app.arduino,"<h,-,10>")
%             pause(45); %Minimum time for hard home; ...
            % used so matlab does not timeout waiting for arduino
            %end
            
            app.YEditField_pos.Enable = 'on';
            app.ZEditField_pos.Enable = 'on';
            app.XEditField_pos.Enable = 'on';
            
            app.YEditField_lim.Enable = 'on';
            app.ZEditField_lim.Enable = 'on';
            app.XEditField_lim.Enable = 'on';
            
            app.YEditField_pos.Value = 0;
            app.ZEditField_pos.Value = 0;
            app.XEditField_pos.Value = 0;
        end


        function [response] = MoveX(app, steps)
            %% Function Name: MoveX
            % Description: Sends the Move X-axis command to arduino.
            % Positive direction defined as away from x motors.
            %
            % Assumptions: steps <= 30,000 to avoid 2complement error.
            % Inputs:
            %   app
            %   steps - (whole number)
            %
            % Outputs:
            %   message - arduino responds axis, direction, steps: "x-1000"
            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            
            steps = round(steps);
            
            if strcmpi(app.XEditField_lim.Enable, 'On')
                
                newposition = app.XEditField_pos.Value + steps*app.mmperstep;
                
                
                if app.XEditField_lim.Value <= newposition
                    uialert(app.UIFigure, 'Next Position exceeds software limits.','warning');
                    error('Movement exceeds software limits')
                end
                
            else
                uialert(app.UIFigure, 'Software Limits not active until Hard Homed','warning');
            end
            
            if steps<0
                direction = "-,";
            else
                direction = "+,";
            end
            
            if app.StopButton.Value ==1
                return
            else
                stepstr = num2str(abs(steps));
                command = strcat('<','x,',direction,stepstr,'>');
                
                response = writeread(app.arduino,command);
            end
            
            if strcmpi(app.XEditField_lim.Enable, 'On')
                app.XEditField_pos.Value = newposition;
            end
            
       end
        
         function [response] = MoveY(app, steps)
            %% Function Name: MoveY
            % Description: Sends the Move Y-axis command to arduino.
            % positive direction defined as away from y motor.
            %
            % Assumptions: steps <= 30,000 to avoid 2complement error.
            % Inputs:
            %   app
            %   steps - (whole number)
            %
            %
            % Outputs:
            %   message - arduino responds axis, direction, steps: "y-1000"
            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            

            steps = round(steps);
            
            
            if strcmpi(app.YEditField_lim.Enable, 'On')
              
                newposition = app.YEditField_pos.Value + steps*app.mmperstep;
                
                
                if app.YEditField_lim.Value <= newposition
                    uialert(app.UIFigure, 'Next Position exceeds software limits.','Warning');
                    error('Movement exceeds software limits')
                end
            else
                uialert(app.UIFigure, 'Software Limits not active until Hard Homed','Warning');
            end
            
          
           if steps<0
               direction = "-,";
           else 
               direction = "+,";
           end
           
           if app.StopButton.Value ==1
               return
           else
            stepstr = num2str(abs(steps));
            command = strcat('<','y,',direction,stepstr,'>'); 
            
            response = writeread(app.arduino,command);
           end
            
                        
            if strcmpi(app.YEditField_lim.Enable, 'On')
                app.YEditField_pos.Value = newposition;
            end          
            
        end
        
        function [response] = MoveZ(app, steps)
              %% Function Name: MoveZ
            % Description: Sends the Move Z-axis command to arduino.
            % positive direction defined as away from z motor. (*Positive is down)
            %
            % Assumptions: steps <= 30,000 to avoid 2complement error.
            % Inputs:
            %   app
            %   steps - (whole number)
            %
            %
            % Outputs:
            %   message - arduino responds axis, direction, steps: "x-1000"
            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________

            
            steps = round(steps);
            
            if strcmpi(app.ZEditField_lim.Enable, 'On')
                
                newposition = app.ZEditField_pos.Value + steps * app.mmperstep;
                
                
                if app.ZEditField_lim.Value <= newposition
                    uialert(app.UIFigure, 'Next Position exceeds software limits.','Warning');
                    error('Movement exceeds software limits')
                end
            else
                uialert(app.UIFigure, 'Software Limits not active until Hard Homed','Warning');
            end
            
            
           if steps<0
               direction = "-,";
           else 
               direction = "+,";
           end
           
           if app.StopButton.Value ==1
               return
           else
            stepstr = num2str(abs(steps));
            command = strcat('<','z,',direction,stepstr,'>'); 
            
            response = writeread(app.arduino,command);
           end
            
          
            if strcmpi(app.ZEditField_lim.Enable, 'On')
                app.ZEditField_pos.Value = newposition;
            end
            
        end


%         function xtrigdelay(app)
%              %needs work
%             
%             temp = app.TemperatureCEditField.Value;
%             dcalib = app.TrigDelayCalibrationDistancemmEditField.Value; %mm
%             c = 1405.3 + (4.632*temp) - (0.03899 * temp * temp)/1000;  %mm/s
%             d = dcalib - app.trigdistance;
%            
%             app.trigdelay = d/c;
%             trigdelaystr = num2str(app.trigdelay);
%            % fprintf(app.Scope, 'HORIZONTAL:DELAY:POSITION %s', trigdelaystr);   command?
%             
%         end

        function [wave,vert,horscale,horpos] = getwave(app)
                
                % clear oscillopscope status to recieve multiple requests
                fprintf(app.Scope, '*CLS');
                %set scope to zero
                %WFMPre:YOFf <NR3>;
                fprintf(app.Scope, 'CH1:WFMPre:YOFf 0e+0');
                % Read YOFFSET 
                app.yOffset = query(app.Scope, 'WFMPre:YOFF?');
                
                % Read YMULT to calculate the vertical values
                app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
                disp('vert')
                vert = app.verticalScale;
                horscale = query(app.Scope, 'Hor:Scale?');
                horpos = query(app.Scope, 'HORizontal:MAIn:POSition?');
              
                app.voltperdiv = query(app.Scope, 'CH1:Scale?');
                app.posvoltdisp = str2double(app.voltperdiv) * 4; %4 divisions in the positive
                app.Scope.Timeout = 30;
               % noisethreshold = 0.0005; %[V] 1 mv  %% determine from signal
                
                % Request 8 bit binary data on the CURVE query
                fprintf(app.Scope, 'DATA:ENCDG RIBINARY;WIDTH 1');
%                 fprintf(app.Scope, '*CLS');  %clear oscilloscope status
                     
                     % Request the CURVE 
                     fprintf(app.Scope, 'CURVE?');
%                      app.yOffset = query(app.Scope, 'WFMPre:YOFF?');
%                      app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
                   
                     % Read binary data and convert to 8-bit int
                     app.wave = (str2double(app.verticalScale) * (binblockread(app.Scope,'int8')))' + str2double(app.yOffset);
                     disp((app.Scope))
                     dt = str2double(horscale)*10/length(app.wave); %us
                     t = dt:dt:(dt)*length(app.wave);
                     wave = [t; app.wave*1000]; % s and mV

        end
        
        function getdata(app)
              %% Function Name: getdata
            % Description: Read data from the scope in binary
            % Immplements a vertical scaling algorithm to center waveform in voltage range recorded.  
            % 
            % Assumptions: Hydrophone connected to CH1
            % Inputs:
            %   app
            %     - app.verticalScale
            %     - app.yOffset
            %     - app.noiseratio (Set to 0.2 - future versions may sample the noise to determine scaling threshold)
            %
            % Outputs:
            %   app.wave 
            %   app.maxV
            %   app.minV
            %
            % Outputs:
            %   message - arduino responds axis, direction, steps: "x-1000"
            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
         getdata = true; 
         
         while getdata
             getdata = false;
             %pause(0.5)
             
             fprintf(app.Scope, '*CLS');  %clear oscilloscope status
             
             %for the second try, update vertical scale here 
             
             
             % Request the CURVE 
             fprintf(app.Scope, 'CURVE?');
           
          
             % Read binary data and convert to 8-bit int
             app.wave = (str2double(app.verticalScale) * (binblockread(app.Scope,'int8')))' + str2double(app.yOffset);
%             figure
%             plot(app.wave)
%               waveform = app.wave;
%               waveform
            
            % outputsync = query(app.Scope, '*OPC?') 
            readerror = query(app.Scope, '*ESR?');
            %event = query(app.Scope, 'EVMSG?');  %getting error 410: query interupted

              
              app.maxV = max(app.wave);
% %               disp('maxV');
% %               disp(app.maxV);
              
              app.minV = min(app.wave);
% %               disp('minV');
% %               disp(app.minV);
             
              
              if strcmpi(app.maxV,'NaN')
                  error("NaN, break out of everything")
              end
              
              
              % ============= Begin Scope Scaling Algorithm ==============
                           
              % app.noiseratio set to 0.2 in runprogrram callback
              if ((app.minV < app.posvoltdisp * (1-app.noiseratio) * -1) || (app.maxV > app.posvoltdisp * (1 - app.noiseratio)))   %check signal not too large           
                  app.breakloop = false;
                  pause(1)
                  getdata = true;
                  
                  app.posvoltdisp = app.posvoltdisp * 1.2;
                  app.voltperdiv = num2str(app.posvoltdisp/4);  %the volt/div resolution is in the hundreth place.
% %                   disp('asking for this voltperdiv:');
% %                   disp(app.voltperdiv);
                  
                  fprintf(app.Scope,'CH1:VOLTs %s', app.voltperdiv);
                  
                  %------------
                  
                  %try 1
                  app.voltperdiv = query(app.Scope, 'CH1:Scale?');
                  app.posvoltdisp = str2double(app.voltperdiv) * 4; %4 divisions in the positive
% %                   disp('actual voltperdiv:');
% %                   disp(app.voltperdiv);
                  
                  % Read YOFFSET             
                    app.yOffset = query(app.Scope, 'WFMPre:YOFF?');
% %                     disp('offset=');
% %                     disp(app.yOffset);
                    
                    % Read YMULT to calculate the vertical values
                    app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
% %                     disp('new vertical scale - not too large:');
% %                     disp(app.verticalScale);
                    
                    pause(1)
                  
              elseif app.breakloop
                  break;
                  
              elseif ((app.minV  > (app.posvoltdisp * -0.1)) || (app.maxV < (app.posvoltdisp * 0.1))) %signal not too small
                  pause(1)
                  getdata = true;
%                   disp('elseif')
                  app.posvoltdisp = app.posvoltdisp * 0.8;
                  
                  
                  if app.posvoltdisp <= 0.008 * 4
                      app.posvoltdisp = 0.008 * 4;               
                      app.breakloop = true;
                      
                  end
                  
                  
                  app.voltperdiv = num2str(app.posvoltdisp/4);
% %                   disp('asking for this voltperdiv:');
% %                   disp(app.voltperdiv);
                  
                  fprintf(app.Scope,'CH1:VOLTs %s', app.voltperdiv);


                   %------------
                   
                   %try 1
                   app.voltperdiv = query(app.Scope, 'CH1:Scale?');
                   app.posvoltdisp = str2double(app.voltperdiv) * 4; %4 divisions in the positive
% %                    disp('actual voltperdiv:');
% %                    disp(app.voltperdiv);
                  
                  % Read YOFFSET             
                    app.yOffset = query(app.Scope, 'WFMPre:YOFF?');
%                     disp('offset=');
%                     disp(app.yOffset);
                    
                    % Read YMULT to calculate the vertical values
                    app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
% %                     disp('new vertical scale - not too small:');
% %                     disp(app.verticalScale);
                    
                    pause(1)

              end

              if getdata
                  app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
                  verticalscaleerror = query(app.Scope, '*ESR?');
                  
              end  
            
         end
         
        end


        function Go2softhome(app)
              %% Function Name: Go2start
            % Description: Moves the motors to the soft home located at center of scan.
            % Called in "RunScanButtonPushed"
            % Position is relative to home. 
            %
            % Assumptions: None
            % Inputs:
            %   app
            %   
            % Outputs: None            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
                    
            xsofthome = app.XEditField_softhome.Value;
            ysofthome = app.YEditField_softhome.Value;
            zsofthome = app.ZEditField_softhome.Value;

            xstartsteps = round(xsofthome/app.mmperstep);
            ystartsteps = round(ysofthome/app.mmperstep);
            zstartsteps = round(zsofthome/app.mmperstep);
            
            if xstartsteps ~= 0 
               x2complement(app, xstartsteps)
            end
            
            if ystartsteps ~= 0
                y2complement(app, ystartsteps)
            end
            
            if zstartsteps ~= 0
                z2complement(app, zstartsteps)
            end                      
        end
        
        function [solution] = aquire3D(app,SH) 
                %% Function Name: aquire3d
            % Description: Aquires a 3d volume as determined by the scan dimensions.      
            %
            % Assumptions: Default software limits assume device starting at home.
            % Inputs:
            %   app
            %
            % Outputs:
            %   solution 
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            xreverse = false;
            yreverse = true;  % starting in 1,1,Yvoxels voxel
            app.lastx = -1;   % these values are opposite of what the starting direction from home is
            app.lasty = -1;
            
            
            %Move to corner to start
            if ~SH
                MoveX(app, (-1 * app.stepspervoxel * round(app.Xvoxels/2,2)))
                MoveY(app, (-1 * app.stepspervoxel * round(app.Yvoxels/2,2)))
                MoveZ(app, (-1 * app.stepspervoxel * round(app.Zvoxels/2,2)))
            end
            
            
            for iz = 1:1:app.Zvoxels
                if (iz ~=1)
                    MoveZ(app, app.stepspervoxel);
                end
                
                app.lastx = -1 * app.lastx;
                for ix = 1:1:app.Xvoxels
                    if (ix ~= 1)
                                               
                        xscale = (0:app.ResolutionmmEditField.Value:app.XEditField_dim.Value);  %pixels to mm
                        yscale = (0:app.ResolutionmmEditField.Value:app.YEditField_dim.Value);
                        
                        imagesc(xscale, yscale,reshape(abs(app.zerom(iz,:,:,2)),app.Xvoxels,app.Yvoxels));
                        %plots a real-time 2D matrix of each yz plane scan
                        
                        colorbar
                        colormap('hsv')
                        
                        MoveX(app, app.stepspervoxel * app.lastx);
                    end
                    app.lasty = -1 * app.lasty;
                    
                    for iy = 1:1:app.Yvoxels
                        if app.StopButton.Value == 1
                             return
                        end
                        if app.PauseButton.Value
                            while app.PauseButton.Value
                                pause(0.2)
                            end
                        end
                        
                        if (iy ~= 1)
                            MoveY(app, app.stepspervoxel * app.lasty);
                        end
                        pause(0.25)
                        
                        
                        getdata(app);
                        
                        
                        if yreverse
                            ymemi = app.Yvoxels - iy + 1;
                        else
                            ymemi = iy;
                        end
                        
                        if xreverse
                            xmemi = app.Xvoxels - ix + 1;
                        else
                            xmemi = ix;
                        end
                        
                        
                        placedata(app, iz, xmemi, ymemi)
                        
                        if ((app.lasty > 0) && (iy == app.Yvoxels))
                            yreverse = false;
                        elseif ((app.lasty < 0) && (iy == app.Yvoxels))
                            yreverse = true;
                        end
                        
                        if ((app.lastx > 0) && (ix == app.Xvoxels))
                            xreverse = true;
                        elseif ((app.lastx < 0) && (ix == app.Xvoxels))
                            xreverse = false;
                        end
                    end
                end
            end
            solution = app.zerom;
            
            
        end
        
%         function [solution] = aquire2DXY(app)
%             %% Function Name: aquire2DXY
%             % Description: Aquires a 2d plane in XY axes as determined by the scan dimensions.
%             %
%             % Assumptions: Default software limits assume device starting at home.
%             % Inputs:
%             %   app
%             %
%             % Outputs:
%             %   solution
%             
%             % Dec. 12, 2019
%             % ___________________
%             
%             
%           %  yreverse = true;
%           %  app.lasty = -1;
%             
%             
%             disp(app.Xvoxels/2)
%             disp(app.stepspervoxel)
%             
%             MoveX(app, (-1 * app.stepspervoxel * round(app.Xvoxels/2,2)))
%             MoveY(app, (-1 * app.stepspervoxel * round(app.Yvoxels/2,2)))
%          
%             disp(app.Xvoxels)
%             disp(app.Yvoxels)
%             pause(5)
%             for ix = 1:1:(app.Xvoxels)
%                 %don't need to move on first aquisition
%                 if (ix ~= 1)
%                     MoveX(app, app.stepspervoxel)
%                 end
%                % app.lasty = -1 * app.lasty;
%                 for iy = 1:1:(app.Yvoxels)
%                     if app.StopButton.Value
%                         return
%                     end
%                     if app.PauseButton.Value
%                         while app.PauseButton.Value
%                             pause(0.2)
%                         end
%                     end
%                     
%                     if (iy ~= 1)
%                      %   MoveY(app, app.stepspervoxel * app.lasty)
%                      MoveY(app, app.stepspervoxel)
%                     end
%                     
%                     getdata(app);
%                     
% %                     if yreverse
% %                         ymemi = app.Yvoxels-iy + 1;
% %                     else
% %                         ymemi = iy;
% %                     end
%                     
%                     disp(ix)
%                     disp(iy)
%                %     disp(ymemi)
%               %      placedata(app, ymemi, ix, -1)
%               placedata(app, iy, ix, -1)
%                     
% %                     
% %                     if (app.lasty < 0 && iy == app.Yvoxels)
% %                         yreverse = true;
% %                     elseif  (app.lasty > 0 && iy == app.Yvoxels)
% %                         yreverse = false;
% %                     end
% 
%                     if (iy == app.Yvoxels)
%                         MoveY(app, app.stepspervoxel * (app.Yvoxels-1) * -1)
%                     end
%                     
%                     
%                 end
%             end
%             solution = app.zerom;
%         end

function [solution] = aquire2DXY(app,SH)
    %% Function Name: aquire2DXY
    % Description: Aquires a 2d plane in XY axes as determined by the scan dimensions.
    %
    % Assumptions: Default software limits assume device starting at home.
    % Inputs:
    %   app
    %
    % Outputs:
    %   solution
    
    % Dec. 12, 2019
    % ___________________
    
    
    xreverse = false;
    app.lasty = -1;
    if ~SH
        MoveX(app, (-1 * app.stepspervoxel * round(app.Xvoxels/2,2)));
        MoveY(app, (-1 * app.stepspervoxel * round(app.Yvoxels/2,2)));
    end
    for iy = 1:1:(app.Yvoxels)
%         disp('Yvoxels')
%         app.Yvoxels
        
        %don't need to move on first aquisition
        if (iy ~= 1)
            
            xscale = [0 app.XEditField_dim.Value*app.ResolutionmmEditField.Value];  %pixels to mm
            yscale = [0 app.XEditField_dim.Value*app.ResolutionmmEditField.Value];
            imagesc(xscale, yscale,abs(app.zerom(:,:,2)));
            colorbar
            colormap('hsv')
            
            MoveY(app, app.stepspervoxel);
        end
        app.lasty = -1 * app.lasty;   %using lasty for x.  just to test.
        
        if app.StopButton.Value == 1
            return
        end
        if app.PauseButton.Value
            while app.PauseButton.Value
                pause(0.2);
            end
        end
        
        
        for ix = 1:1:(app.Xvoxels)
            
            if (ix ~= 1)
                MoveX(app, app.stepspervoxel * app.lasty);
            end
            
%             inserted pause to allow for averaging
            pause(0.25)
            
            getdata(app);
            if xreverse
                xmemi = app.Xvoxels-ix + 1;
            else
                xmemi = ix;
            end
            
% %             disp(iy)
% %             disp(xmemi)
            
           
            placedata(app, iy, xmemi, -1)
            
            if (app.lasty > 0 && ix == app.Xvoxels)
                xreverse = true;
            elseif  (app.lasty < 0 && ix == app.Xvoxels)
                xreverse = false;
            end
        end
    end
    solution = app.zerom;
end

function [solution] = aquire2DXZ(app,SH)
    %% Function Name: aquire2DXZ
    % Description: Aquires a 2d plane in XZ axes as determined by the scan dimensions.
    %
    % Assumptions: Default software limits assume device starting at home.
    % Inputs:
    %   app
    %
    % Outputs:
    %   solution
    %
    % Last Edited by: Sam Clinard
    % Jun 22, 2020
    % ___________________
    
    xreverse = false;
    app.lastx = -1;
    if ~SH
        MoveX(app, (-1 * app.stepspervoxel * round(app.Xvoxels/2,2)))
        MoveZ(app, (-1 * app.stepspervoxel * round(app.Zvoxels/2,2)))
    end
    for iz = 1:1:app.Zvoxels
        if (iz ~= 1)
            xscale = [0 app.XEditField_dim.Value*app.ResolutionmmEditField.Value];  %pixels to mm
            yscale = [0 app.XEditField_dim.Value*app.ResolutionmmEditField.Value];
            imagesc(xscale, yscale,abs(app.zerom(:,:,2)))
            colorbar
            colormap('hsv')
            
            MoveZ(app, app.stepspervoxel);
        end
        app.lastx = -1 * app.lastx;
        for ix = 1:1:app.Xvoxels
            if app.StopButton.Value == 1
                return
            end
            if app.PauseButton.Value
                while app.PauseButton.Value
                    pause(0.2)
                end
            end
            
            if (ix ~=1)
                MoveX(app, app.stepspervoxel * app.lastx);
            end
            
            pause(0.25)
            if xreverse
                xmemi = app.Xvoxels - ix + 1;
            else
                xmemi = ix;
            end
                        
            getdata(app);
            
            placedata(app, iz, xmemi, -1)
            
            if (app.lastx > 0 && ix == app.Xvoxels)
                xreverse = true;
            elseif (app.lastx < 0 && ix == app.Xvoxels)
                xreverse = false;
            end
            
        end
    end
    solution = app.zerom;
end
        
        function [solution] = aquire2DYZ(app,SH)
            %% Function Name: aquire2DYZ
            % Description: Aquires a 2d plane in YZ axes as determined by the scan dimensions.
            %
            % Assumptions: Default software limits assume device starting at home.
            % Inputs:
            %   app
            %
            % Outputs:
            %   solution
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            
            yreverse = false;
            app.lasty = -1;
            if ~SH
                MoveY(app, (-1 * app.stepspervoxel * round(app.Yvoxels/2,2)))
                MoveZ(app, (-1 * app.stepspervoxel * round(app.Zvoxels/2,2)))
            end
            for iz = 1:1:app.Zvoxels
                if (iz ~= 1)
                    xscale = [0 app.XEditField_dim.Value*app.ResolutionmmEditField.Value];  %pixels to mm
                    yscale = [0 app.XEditField_dim.Value*app.ResolutionmmEditField.Value];
                    imagesc(xscale, yscale,abs(app.zerom(:,:,2))) 
                    colorbar
                    colormap('hsv')          
                    MoveZ(app, app.stepspervoxel)
                end
                
                app.lasty = -1 * app.lasty;
                
                for iy = 1:1:app.Yvoxels
                    if app.StopButton.Value == 1
                        return
                    end
                    if app.PauseButton.Value
                        while app.PauseButton.Value
                            pause(0.2)
                        end
                    end
                    
                    if (iy~=1)
                        MoveY(app, app.lasty*app.stepspervoxel);
                    end
                    
                    pause(0.25)
                    if yreverse
                        ymemi = app.Yvoxels - iy +1;
                    else
                        ymemi = iy;
                    end
                    
                    getdata(app);
                    
                    placedata(app, iz, ymemi, -1);
                    
                    if (app.lasty > 0 && iy == app.Yvoxels)
                        yreverse = true;
                    elseif (app.lasty < 0 && iy == app.Yvoxels)
                        yreverse = false;
                    end 
                end
            end
            solution = app.zerom;
        end

        
        function [solution] = aquire1DX(app)
                 %% Function Name: aquire1DX
            % Description: Aquires a 1d line in X axis as determined by the scan dimensions.      
            %
            % Assumptions: Default software limits assume device starting at home.
            % Inputs:
            %   app
            %
            % Outputs:
            %   solution
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            MoveX(app, (-1 * app.stepspervoxel * round(app.Xvoxels/2,2)));
            
            disp('Xvoxels:');
            disp(app.Xvoxels);
            
            for ix = 1:1:app.Xvoxels
                disp('ix:');
                disp(ix);
                              
                if app.StopButton.Value == 1
                    disp("stopping");
                    return
                end
                
                if app.PauseButton.Value
                    while app.PauseButton.Value
                        disp("pausing");
                        pause(0.2)
                    end
                end
                
                if (ix ~= 1)                
                    MoveX(app, app.stepspervoxel);
                end
                pause(0.25)
                getdata(app);
                
                disp('done w get data');
                
                placedata(app, ix, -1, -1);
                
            end
            
            solution = app.zerom;
            
        end
        
        function [solution] = aquire1DY(app)
              %% Function Name: aquire1DY
            % Description: Aquires a 1d line in Y axis as determined by the scan dimensions.      
            %
            % Assumptions: Default software limits assume device starting at home.
            % Inputs:
            %   app
            %
            % Outputs:
            %   solution
            
            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            
            MoveY(app, (-1 * app.stepspervoxel * round(app.Yvoxels/2,2)))
            
            
            for iy = 1:1:app.Yvoxels
                if app.StopButton.Value == 1
                    return
                end
                
                if app.PauseButton.Value
                    while app.PauseButton.Value
                        pause(0.2)
                    end
                end
                
                if (iy ~= 1)
                    
                    MoveY(app, app.stepspervoxel)
                end
                pause(0.25)
                getdata(app);
                
                placedata(app, iy, -1, -1)
            end


            solution = app.zerom;
        end
        
        function [solution] = aquire1DZ(app)
              %% Function Name: aquire1DZ
            % Description: Aquires a 1d line in Z axis as determined by the scan dimensions.      
            %
            % Assumptions: Default software limits assume device starting at home.
            % Inputs:
            %   app
            %
            % Outputs:
            %   solution
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            MoveZ(app, (-1 * app.stepspervoxel * round(app.Zvoxels/2,2)))
            
            for iz = 1:1:app.Zvoxels
                if app.StopButton.Value == 1
                    return
                end
                if app.PauseButton.Value
                    while app.PauseButton.Value
                        pause(0.2)
                    end
                end
                
                if (iz ~= 1)                    
                    MoveZ(app, app.stepspervoxel)
                end
                
                pause(0.25)
                getdata(app);
                
                placedata(app, iz, -1, -1)
                
            end
            
            solution = app.zerom;
            
        end
        
        
        function x2complement(app, steps)
              %% Function Name: x2complement
            % Description: avoids 2 complement errors which occurs in large numbers. (sign changes resulting in movement in the opposite direction)    
            % Used in go2start and move functions
            %
            % Assumptions: whole number steps
            % Inputs:
            %   app
            %   steps - Number of motor steps to move in x-axis
            %   
            % Outputs: None
            %
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            disp(steps)
            if abs(steps) < 30000
                [~] = MoveX(app, steps);
                
            else
                movesign = sign(steps);
                wholesect = round(steps/30000);
                partsect = rem(steps,30000);
                
                for loop = 1:wholesect
                    [~] = MoveX(app, 30000*movesign);
                end
                [~] = MoveX(app, partsect*movesign);
            end           
            
        end
        
        function y2complement(app, steps)
            % Y-axis version of x2complement above.
            disp(steps)
            if abs(steps) < 30000
                [~] = MoveY(app, steps);
                
            else
                movesign = sign(steps);
                wholesect = round(steps/30000);
                partsect = rem(steps,30000);
                
                for loop = 1:wholesect
                    [~] = MoveY(app, 30000*movesign);
                end
                [~] = MoveY(app, partsect*movesign);
            end          
            
        end
        
        function z2complement(app, steps)
            % Z-axis version of x2complement above.
            
            if abs(steps) < 30000
                [~] = MoveZ(app, steps);
                
            else
                movesign = sign(steps);
                wholesect = round(steps/30000);
                partsect = rem(steps,30000);
                
                for loop = 1:wholesect
                    [~] = MoveZ(app, 30000*movesign);
                end
                [~] = MoveZ(app, partsect*movesign);
            end
            
        end

        function placedata(app, first, second, third)
                  %% Function Name: placedata
            % Description: Saves data in matrix or CSV according to the index inputs.
            % 3d data has all three indices > 0
            % 2d data has first and second > 0.  Third set to -1.
            % 1D data has first > 0.  Second and third set to -1. 
            %
            % Assumptions: Indices must be in same order as relevant aquire program.
            %
            % Inputs:
            %   app
            %   first, second, third
            %   
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
           
            
            if third >= 0
                if strcmp(app.SaveAsDropDown.Value, 'Matrix') || strcmp(app.SaveAsDropDown.Value, 'Matrix and CSV')
                    app.zerom(first, second, third, 1) = app.maxV;
                    app.zerom(first, second, third, 2) = app.minV;
                    if strcmp(app.SaveWaveinMatrixSwitch.Value, 'On')
                        for index = 3:1:2502
                            app.zerom(first, second, third, index) = app.wave(index - 2);  
                        end
                    end
                end
                
                
                if (strcmp(app.SaveAsDropDown.Value, 'CSV') || strcmp(app.SaveAsDropDown.Value,'Matrix and CSV'))
                    firststr = num2str(first);
                    secondstr = num2str(second);
                    thirdstr = num2str(third);
                    
                    fprintf(app.fdata, '%s, %s, %s, %s, %s, ', firststr, secondstr, thirdstr, num2str(app.maxV), num2str(app.minV));
                                        
                    waveString = sprintf('%0.5f,', app.wave);
                    waveString = waveString(1:end-1);% strip final comma
                    fprintf(app.fdata,'%s \n',waveString);  
                    
                end
            end
            
           
            if (third < 0) && (second > 0)
                if strcmp(app.SaveAsDropDown.Value, 'Matrix') || strcmp(app.SaveAsDropDown.Value, 'Matrix and CSV')
                    app.zerom(first, second, 1) = app.maxV;
                    app.zerom(first, second, 2) = app.minV;
                    app.zerom(first, second, 2)
                    
                    if strcmp(app.SaveWaveinMatrixSwitch.Value, 'On')
                        for index = 3:1:2502
                            app.zerom(first, second, index) = app.wave(index - 2);
                        end
                    end
                end
                
                
                if (strcmp(app.SaveAsDropDown.Value, 'CSV') || strcmp(app.SaveAsDropDown.Value,'Matrix and CSV'))
                    firststr = num2str(first);
                    secondstr = num2str(second);
                    
                    fprintf(app.fdata, '%s, %s, %s, %s, ', firststr, secondstr, num2str(app.maxV), num2str(app.minV));
                    
                    start = tic(); %#ok<NASGU> 
                                        
                    waveString = sprintf('%0.5f,', app.wave);
                    waveString = waveString(1:end-1);% strip final comma
                    fprintf(app.fdata,'%s \n',waveString);                
                    
%                     fortime = toc(start)
                end
                
            end
            
            if (third < 0) && (second < 0)
                if strcmp(app.SaveAsDropDown.Value, 'Matrix') || strcmp(app.SaveAsDropDown.Value, 'Matrix and CSV')
                    app.zerom(first, 1) = app.maxV;
                    app.zerom(first, 2) = app.minV;
                    if strcmp(app.SaveWaveinMatrixSwitch.Value, 'On')
                        for index = 3:1:2502
                            app.zerom(first, index) = app.wave(index - 2);
                        end
                    end
                end
                
                
                if (strcmp(app.SaveAsDropDown.Value, 'CSV') || strcmp(app.SaveAsDropDown.Value,'Matrix and CSV'))
                    firststr = num2str(first);
                    
                    fprintf(app.fdata, '%s, %s, %s, ', firststr, num2str(app.maxV), num2str(app.minV));
                                        
                    waveString = sprintf('%0.5f,', app.wave);
                    waveString = waveString(1:end-1);% strip final comma
                    fprintf(app.fdata,'%s \n',waveString);  
                end
            end
        end

        function GenerateImages(app, solution)
            %% Function Name: GenerateImages
            % Description: Generates images of 2d scans.
            %
            % Assumptions: 
            %
            % Inputs:
            %   app
            %   solution - the matrix to be plotted. 
            %   
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
             disp(solution)
            program = app.ProgramDropDown.Value;
            app.hydroPress_cal = (app.HydrophoneCalibrationValueEditField.Value);
            
            sizesol = size(solution);
                Nx = sizesol(1);
                Ny = sizesol(2);
                
            if strcmp(program(1:2),'2D') || strcmp(program(1:2),'3D')
               
                app.Maxmat = abs(solution(:,:,1))/app.hydroPress_cal;
                app.Minmat = abs(solution(:,:,2))/app.hydroPress_cal;
                
                maxlocation = strcat(app.path, 'PeakPosImage');
                minlocation = strcat(app.path, 'PeakNegImage');
                solutionlocation = strcat(app.path, 'Solution');
                pplocation = strcat(app.path, 'PlotProfile');


                xscale = [0 Nx*app.ResolutionmmEditField.Value];  %pixels to mm
                yscale = [0 Ny*app.ResolutionmmEditField.Value];
                maximage = imagesc(xscale, yscale, app.Maxmat);
                colormap('hsv')
                h = colorbar;
                set(get(h,'Title'),'String','Mpa');
                
                title('Peak Positive Pressure')
                xlabel('mm')
                ylabel('mm')
                
                saveas(maximage, maxlocation, 'png');
                
             
                xscale = [0 Nx*app.ResolutionmmEditField.Value];  %pixels to mm
                yscale = [0 Ny*app.ResolutionmmEditField.Value];
                minimage = imagesc(xscale, yscale, app.Minmat);
                colormap('hsv')
                h = colorbar;
                set(get(h,'Title'),'String','MPa');
                title('Min Image')
                xlabel('mm') 
                ylabel('mm') 
                
                saveas(minimage, minlocation, 'png');
                
                save(solutionlocation, 'solution')
                oneD = false;
            else
                oneD = true;                                
                app.Maxmat = abs(solution(:,1))/app.hydroPress_cal;
                app.Minmat = abs(solution(:,2))/app.hydroPress_cal;
                
                maxlocation = strcat(app.path, 'PeakPosImage');
                minlocation = strcat(app.path, 'PeakNegImage');
                solutionlocation = strcat(app.path, 'Solution');
                pplocation = strcat(app.path, 'PlotProfile');
                
                switch program
                   case '1DX'
                       range = 0:app.ResolutionmmEditField.Value:(app.XEditField_dim.Value-app.ResolutionmmEditField.Value); 
                      
                   case '1DY'
                       range = 0:app.ResolutionmmEditField.Value:(app.YEditField_dim.Value-app.ResolutionmmEditField.Value); 
                      
                   case '1DZ'
                       range = 0:app.ResolutionmmEditField.Value:(app.ZEditField_dim.Value-app.ResolutionmmEditField.Value); 
                                         
                end
                figure
                maxP = plot(range, app.Maxmat);
                title('Peak Positive Pressure')
                xlabel('[mm]')
                
                saveas(maxP, maxlocation, 'png');
                
             
                minP = plot(range, app.Minmat);
                title('Peak Negative Pressure')
                xlabel('[mm]')
                
                saveas(minP, minlocation, 'png');
                
                save(solutionlocation, 'solution')
                
            end
            %FWHM
           
            if app.FWHMSwitch.Value == "On"
                    [rt, ct, zt] = size(solution);
                    if strcmp(app.SaveWaveinMatrixSwitch.Value, 'On')
                         zt = zt/2500;                       
                    end
                    if ~oneD
                        [ridx,cidx,zidx] = ind2sub(size(solution),find(solution == max(solution(:)),1,'first'));
                        if rt >= ct && rt >= zt
                            sol = solution(:,cidx,zidx);
                            midx = ridx;
                            N = rt;
                        elseif ct >= rt && ct >= zt
                            sol = solution(ridx,:,zidx);
                            midx = cidx;
                            N = ct;
                        else
                            sol = solution(ridx,cidx,:);
                            midx = zidx;
                            N = zt;
                        end
                    else
                        sol = solution(:,1);
                        N = length(sol);
                        [~,midx] = max(sol);
                    end
                    dist = app.ResolutionmmEditField.Value:app.ResolutionmmEditField.Value:N*app.ResolutionmmEditField.Value;
                    distq = app.ResolutionmmEditField.Value/100:app.ResolutionmmEditField.Value/100:N*app.ResolutionmmEditField.Value;
                    figure
                    csol = sol(:)';
                    solq = interp1(dist,csol,distq);
                    PlotProfile = plot(distq,solq);
                    hold on
                    title('2D Plot Profile')
                    xlabel('Distance (mm)')
                    ylabel('Pressure (MPa)')
                    [~,lidx] = min(abs(solq(1:midx*100)-max(sol)/2));
                    [~,uidx] = min(abs(solq(lidx+1:end)-max(sol)/2));
                    uidx = uidx + lidx;
                    fwhm = distq(uidx)-distq(lidx);
                    legend(['FWHM=' num2str(fwhm)])
                    saveas(PlotProfile, pplocation, 'png');
            end
            
        end


        function [return_mm] = center_offset(~, linescan)         
                    
                neg_v = abs(linescan(:,2));
                                         
                [~, i] = max(neg_v);
                
                return_mm = (length(linescan)-i)/5; %scan in 0.2 mm increments
                           
        end
        
    end
    
    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                  %% Function Name: startupFcn
            % Description: Connects and configures function generator, scope, and Arduino.      
           %
            % Troubleshooting: 1) Check physical connection and try again. 
            % 2) For scope and generatore check device adress is correct (found in visa -> instrument manager)
            % 3) For Arduino, check com port (Found in Device Manager)
            % 4) enter instrhwinfo('visa') in command window. Response should be "InstalledAdaptors: {'tek'}"
            %    *Note- Tektronix visa does not play well with others.  Only have tek visa installed.
            % 5) check visa constructor names match device adresses.  
            %    -enter in command window "name = insthwinfo('visa','tek')" 
            %    - then name.ObjectConstructorName{1} and name.ObjectConstructorname{2}
            %
            % Inputs: app   
            % Outputs:None
            
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________


            instrreset
            
            %Driver name ('tek') must be changed if using different visa
            %Resource name must be changed for different generator or oscilloscope.
            %Generator Model: Tektronix AFG3022C
            %Scope Model: Tektronix TDS 2001C
            %The generator and scope are controlled with SCPI commands. See programming manuals.
            
            try
                app.DateEditField.Value = datestr(now,'mm-dd-yyyy HH:MM');
                %ceates visa object for generat. 
                app.MyFgen = visa('Tek', app.GenAddress);
        
                set (app.MyFgen,'OutputBufferSize',100000);
                
                fopen(app.MyFgen);
                pause(0.5)
                
                app.Lamp2.Color = 'green';
            catch exception
                mymessage = 'Unable to connect generator.';
                uialert(app.UIFigure, {exception.message, mymessage}, 'Connection error');
                return
            end
            
            
            %Query Idendity string and report
            idn = query (app.MyFgen, '*IDN?');
            disp(idn)
            
            pause(0.5)
            
       try
                %creates VISA Scope Object
                app.Scope = visa('Tek', app.ScopeAddress);
              
                app.Scope.InputBufferSize = 2500;
                app.Scope.OutputBufferSize = 2500;
       
                app.Scope.Timeout = 20;
                app.Scope.ByteOrder = 'littleEndian';
              
                fopen(app.Scope);
                
               % get(app.Scope,{'EOSMode','EOIMode'})
                pause(0.5)
       catch
                mymessage = 'Unable to connect Oscilloscope';
                disp(mymessage)
                
                
        end

            %Query idendity string and report
            idn = query(app.Scope, '*IDN?');
            disp(idn)  
            
            %need to test this works
            if ~strcmp(idn, '')
                app.Lamp3.Color = 'Green';
            end
                       
             try
                 disp(app.arduino)
                app.arduino = serialport(app.ArduinoPort,115200,"Timeout", 150);
                disp(app.arduino)
                configureTerminator(app.arduino,"CR");
                
                ready_resp = "";
                readyMessage = "Arduino is ready";
                while ~strcmp(ready_resp,readyMessage)
                    ready_resp = readline(app.arduino)
                end
                
                app.Lamp.Color = 'Green';
                
            catch exception
                mymessage = 'Unable to connect arduino';
                uialert(app.UIFigure, {exception.message, mymessage}, 'Connection error');
                return
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequestFCN(app, event)
           %Opens closing confirmation window
            
            question = 'Close app?';
            uiconfirm(app.UIFigure, question, 'Confirm Close',...
                'CloseFcn',@(src, event) closeApp_Callback(app,src,event));
            
        end

        % Callback function
        function ConnectOscilloscopeButtonPushed(app, event)
                   %% Function Name: ConnectOscillscopeButtonPushed
            % Description: Creates a visa connection to the scope. 
            % Code should match scope portion of startupFcn.
            %
            % Troubleshooting:  See startupFcn
            %
            % Input: app, event
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________  
            
            try
                %create and connect visa scope object 
                app.Scope = visa('Tek',app.ScopeAddress);
                
                app.Scope.InputBufferSize = 2500;  % Set the buffer size
                app.Scope.OutputBufferSize = 2500;               
                app.Scope.Timeout = 20;       %note if timeout is terminating commands there is a problem *****
                app.Scope.ByteOrder = 'littleEndian';  % Set the Byte order
                fopen(app.Scope);
                pause(0.5)
            catch exception
                mymessage = 'Unable to connect Oscilloscope';
                uialert(app.UIFigure, {exception.message, mymessage}, 'Connection error');
                
                
            end

            %Query Idendity string and report
            idn = query(app.Scope, '*IDN?');
                        
            if ~strcmp(idn, '')
                app.Lamp3.Color = 'Green';
            end

        end

        % Callback function
        function ConnectGeneratorButtonPushed(app, event)
             %% Function Name: ConnectGeneratorButtonPushed
            % Description: Creates a visa connection to the generator. 
            % Code should match generator portion of startupFcn.
            %
            % Troubleshooting:  See startupFcn
            %
            % Input: app, event
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            try
                                
                %ceates visa object
                app.MyFgen = visa('Tek', app.GenAddress);
                
                set (app.MyFgen,'OutputBufferSize', 100000);
                               
                fopen(app.MyFgen);
                
            catch exception
                mymessage = 'Verify that the Resource and Driver names you provided are valid.';
                
                uialert(app.UIFigure, {exception.message, mymessage}, 'Connection error');
                return
            end
            %Query Idendity string and report
            idn = query(app.MyFgen, '*IDN?');
            disp(idn)
            if ~strcmp(idn, '')
                app.Lamp2.Color = 'Green';
            end
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
                 %% Function Name: GenerateButtonPushed
            % Description: Generates function set on Function Generator tab of GUI.
            %  Commands are found in the programmer manual and must be changed for other instruments.
            % 
            % Assumptions: Tektronix AFG3022C Function Generator
            %
            % Input: app, event
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % ___________________
            
            
            if strcmpi(app.MyFgen.Status, 'closed')
                ConnectGeneratorButtonPushed(app,[])
            end
            
            Amp1 = app.AmplitudeVEditField.Value;
            Freq1 = app.FrequencyHzEditField.Value;
            FuncType = app.FunctionDropDown.Value;
            mode = app.RunModeDropDown.Value;
            numCycles = app.NumCyclesEditField.Value;
            Trigdelay = app.TrigDelaymsEditField.Value;
            Interval = app.IntervalmsEditField.Value;
            offset = app.OffsetVEditField.Value;
            phase = app.PhaseDegEditField.Value;
            phase = deg2rad(phase);
            
            
            switch mode
                case 'Continuous'
                     fprintf(app.MyFgen, '*RST');    %continuous is the default run mode.  To set just reset the scope.
                     
                case 'Burst'
                    fprintf(app.MyFgen, 'BURST:MODE TRIG');
                    fprintf(app.MyFgen, 'BURST:NCycles %f', numCycles);
                    fprintf(app.MyFgen, 'BURST:TDelay %f ms', Trigdelay);
                    fprintf(app.MyFgen, 'BURST:STAT ON');
                    fprintf(app.MyFgen, 'TRIGGER:TIMER %f ms', Interval);
                    
            end
            
            
            fprintf(app.MyFgen,'FUNCTION %s', FuncType);
            
            fprintf(app.MyFgen,'VOLT %d', Amp1); % set max waveform amplitude to 2 Vpp
            
            fprintf(app.MyFgen, 'VOLTAGE:OFFSET %f v', offset); % set offset
            offset = query(app.MyFgen, "Voltage:Offset?");  % If the offset won't change. Check that offset + amplitude <= 10.
            app.OffsetVEditField.Value = str2num(offset);
            
            fprintf(app.MyFgen, 'FREQUENCY %f', Freq1); %set frequency
            Freq1 = query(app.MyFgen, "Freq?");    %Frequency range limited in ramp and square
            app.FrequencyHzEditField.Value = str2num(Freq1);
            
            fprintf(app.MyFgen, 'PHASE %f', phase);
            
            %Enable Output
            fprintf(app.MyFgen,'OUTPUT ON'); % turn on channel 1 output
            
            % Read Error
            fprintf(app.MyFgen, 'SYST:ERR?');
            errorstr = fscanf(app.MyFgen);
            
            % error checking
            if strncmp (errorstr, '+0,"No error"',13)
                errorcheck = 'Waveform generated without any error \n';
                fprintf (errorcheck)
            else
                errorcheck = ['Error reported: ', errorstr];
                fprintf (errorcheck)
            end
            
            %closes the visa session with the function generator
            fclose(app.MyFgen);
            
            
        end

        % Callback function
        function GenerateReadMeButtonPushed(app, event)
            %Generates a Read_Me.txt without running the program. 
            
            [~, app.path] = uiputfile('*mat');
            ReadMeGenerateFunc(app);
        end

        % Button pushed function: ArduinoButton
        function ArduinoButtonPushed(app, event)
             %% Function Name: ArduinoButtonPushed
            % Description: Creates a serialport connection to arduino                                                                                                                                                                                                                                                                                                                      connection to the Arduino. 
            % Code should match ardunio portion of startupFcn.
            %
            %
            % Input: app, event
            % Outputs: None
            %
            % Last edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            try
                app.arduino = serialport(app.ArduinoPort,115200, "Timeout", 150);
                
                ready_resp = "";
                readyMessage = "Arduino is ready";
                configureTerminator(app.arduino,"CR");
                while ~strcmp(ready_resp,readyMessage)
                    ready_resp = readline(app.arduino);
                end
                
                app.Lamp.Color = 'Green';
                
            catch exception
                mymessage = 'Unable to connect arduino';
                uialert(app.UIFigure, {exception.message, mymessage}, 'Connection error');
                return
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
                %% Function Name: ApplyButtonPushed
            % Description: Changes Settings on Scope
            % Uses SCPI commands which are dependent on scope model.  See programming manual. 
            % 
            % Assumptions: None
            %
            % Input: app, event
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            
            
            couple = app.CouplingDropDown.Value;    %AC | DC | GND
            TrigSource = app.TrigSourceDropDown.Value;  % CH1 | CH2 | EXT 
            Attenuation = app.AttenuationDropDown.Value;  % 1 |10| 20 |50|100|1000
            avgMode = app.AcquireModeDropDown.Value; % Average | Sample | Peak Detect
            numAvg = app.AverageDropDown.Value; %4 | 16 | 64 |128  
            VertScale = app.VerticalScaleVEditField.Value;
            horscale = app.HorizontalScalesEditField.Value;
            trigcouple = app.TrigCoupleDropDown.Value; %DC | AC
            slope = app.SlopeDropDown.Value;
            horpos = app.HorizontalPosition.Value;
            
            if strcmpi(app.Scope.Status, 'closed')
                fopen(app.Scope);
            end
            
            
            fprintf(app.Scope,'CH1:Coupling %s', couple);
            fprintf(app.Scope,'CH1:Probe %s', Attenuation);
            fprintf(app.Scope,'CH1:Position 0');  
            fprintf(app.Scope,'CH1:VOLTs %s', VertScale);
            fprintf(app.Scope,'DATa:Source CH1');
            
      
            fprintf(app.Scope, 'HOR:SCALE %s', horscale); %"sets the time per division for the window time base
            horscale = query(app.Scope, 'Hor:Scale?');
            app.HorizontalScalesEditField.Value = horscale;
            
            fprintf(app.Scope, 'HORizontal:MAIn:POSition %s', horpos);
            
            fprintf(app.Scope, 'MEASU:Immed:Source CH1');

            %acquire settings
            fprintf(app.Scope, 'ACQUIRE:MODE %s', avgMode);
            if strcmpi(avgMode,'AVERAGE')
            fprintf(app.Scope, 'ACQUIRE:NUMAVg %s', numAvg);
            end
            
            %trigger
            fprintf(app.Scope, 'TRIG:MAIN:EDGE:SOURCE %s', TrigSource);
            fprintf(app.Scope, 'TRIG:MAIN:EDGE:COUPLING %s', trigcouple);
            fprintf(app.Scope, 'TRIG:MAIN:EDGE:Slope %s', slope);
        end

        % Button pushed function: HardHomeButton
        function HardHomeButtonPushed(app, event)
            % Description: Moves to hard home defined by the limit switches. 
       %while app.StopButton.Value ~= 1
            [~] = GoHome(app);
      % end
            
        end

        % Callback function
        function XlimitEditFieldValueChanged(app, event)
            % Description: Software limit
            if app.XlimitEditField.Value > 700
               uialert(app.UIFigure, "X-limit exceeds size of tank.",'Warning')
            end
        end

        % Callback function
        function YlimitEditFieldValueChanged(app, event)
            % Description: Software limit
             if app.YlimitEditField.Value > 250
               uialert(app.UIFigure, "Y-limit exceeds size of tank.","warning")
            end
        end

        % Callback function
        function ZlimitEditFieldValueChanged(app, event)
            % Description: Software limit
             if app.ZlimitEditField.Value > 250
               uialert(app.UIFigure, "Z-limit exceeds size of tank.",'Warning')
            end
        end

        % Button pushed function: ResetGeneratorButton
        function ResetGeneratorButtonPushed(app, event)
            %Resets the generator to default.  Turns off channel
            
            if strcmpi(app.MyFgen.Status, 'closed')
                ConnectGeneratorButtonPushed(app,[])
            end
            
            %Clear and reset instrument
            fprintf (app.MyFgen, '*RST');
            fprintf (app.MyFgen, '*CLS');
            
            fclose(app.MyFgen);
        end

        % Button pushed function: ResetScopeButton
        function ResetScopeButtonPushed(app, event)
            %Clear and reset scope
            fprintf (app.Scope, '*RST');
            fprintf (app.Scope, '*CLS');
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            X = app.XEditField_move.Value;
            xmovesteps = round(X/app.mmperstep);
            x2complement(app, xmovesteps)
        end

        % Button pushed function: XButton_2
        function XButton_negative(app, event)
            %negative direction
            X = app.XEditField_move.Value;
            xmovesteps = -1 * round(X/app.mmperstep);
            x2complement(app, xmovesteps)
           
        end

        % Button pushed function: YButton
        function YButton_negative(app, event)
            %negative direction
            Y = app.YEditField_move.Value;
            ymovesteps = -1 * round(Y/app.mmperstep);
            y2complement(app, ymovesteps)
        end

        % Button pushed function: YButton_2
        function YButtonPushed(app, event)
            Y = app.YEditField_move.Value;
            ymovesteps = round(Y/app.mmperstep);
            y2complement(app, ymovesteps)
        end

        % Button pushed function: ZButton
        function ZButton_negative(app, event)
            %negative direction
            Z = app.ZEditField_move.Value;
            zmovesteps = -1 * round(Z/app.mmperstep);
            z2complement(app, zmovesteps)
        end

        % Button pushed function: ZButton_2
        function ZButtonPushed(app, event)
            Z = app.ZEditField_move.Value;
            zmovesteps = round(Z/app.mmperstep);
            z2complement(app, zmovesteps)
        end

        % Button pushed function: RunScanButton
        function RunScanButtonPushed(app, event)
            %% Function Name: RunProgramButtonPushed
            % Description: Runs Scan in four parts
            % 1) Set up properties
            % 2) Set up data storing
            % 3) Aquire data
            % 4) Save data
            %
            % Assumptions: Select new folder for each scan
            %
            % Input: app, event
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________
            try
            tic
            
            [file, app.path] = uiputfile('*mat');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            xsofthome = app.XEditField_softhome.Value;  %mm
            ysofthome = app.YEditField_softhome.Value;
            zsofthome = app.ZEditField_softhome.Value;
            
            
            xdim = app.XEditField_dim.Value; %mm
            ydim = app.YEditField_dim.Value;
            zdim = app.ZEditField_dim.Value;         
            
            xlim = app.XEditField_lim.Value; %mm
            ylim = app.YEditField_lim.Value;
            zlim = app.ZEditField_lim.Value;
            
            selectedSHButton = get(app.SoftHomePositionButtonGroup,"SelectedObject");
            choice = get(selectedSHButton,'Text');
            if strcmp(choice, 'Scan Corner')
                SH = true;
            else
                SH = false;
            end

            %set up properties
  
            app.stepspervoxel = round((app.ResolutionmmEditField.Value)/app.mmperstep);  %whole steps per voxel            
            app.realresolution = app.mmperstep * app.stepspervoxel;  %mm per voxel after rounding
            
            if app.ResolutionmmEditField.Value ~= app.realresolution
                uialert(app.uifigure,'resolution updated to avoid rounding errors','Warning')
                app.ResolutionmmEditField.Value = app.realresolution;
            end
            
            
            x = xdim/app.realresolution; %dimensionless (mm/mm)
            y = ydim/app.realresolution; 
            z = zdim/app.realresolution; 
                                       
            app.Xvoxels = round(x);  %round to whole number of voxels
            app.Yvoxels = round(y);
            app.Zvoxels = round(z);
            
            if xdim ~= app.Xvoxels * app.realresolution
                app.XEditField_dim.Value = xdim;
                uialert(uifigure,'X dimension updated to avoid rounding errors','Warning')
            end
            
            if ydim ~= app.Yvoxels * app.realresolution
                app.YEditField_dim.Value = ydim;
                uialert(uifigure,'Y dimension updated to avoid rounding errors','Warning')
            end
            
            if zdim ~= app.Zvoxels * app.realresolution
                app.ZEditField_dim.Value = zdim;
                uialert(uifigure,'Z dimension updated to avoid rounding errors','Warning')
            end
                
            
            %set up data storing
            csvbool = false;
            matrixbool = false;
            ZeroMatrix(app);
            app.Scope.Timeout = 60;
            
            if strcmp(app.SaveAsDropDown.Value, 'Matrix') || strcmp(app.SaveAsDropDown.Value, "Matrix and CSV")
                matrixbool = true;
            end
            
            if strcmp(app.SaveAsDropDown.Value, 'CSV') || strcmp(app.SaveAsDropDown.Value, "Matrix and CSV")
                csvbool = true;
                csvlocation = strcat(app.path, '\data.txt');
                app.fdata = fopen(csvlocation, 'w');
              
                
                xdim_str = num2str(xdim);
                ydim_str = num2str(ydim);
                zdim_str = num2str(zdim);
                res = num2str(app.ResolutionmmEditField.Value);

                
            end
            
            
            program = app.ProgramDropDown.Value;

                        
            %creates ReadMe with scan characteristics
            ReadMeGenerateFunc(app)
             
           HHselect = uiconfirm(app.UIFigure,...
           'Would you like to Hard Home the system first?',...
           'Scan Home Confirmation',...
           'Options',{'Yes','No','Cancel'},...
           'DefaultOption',2,'CancelOption',3);
            if strcmp(HHselect,'Yes')
                GoHome(app)
            else
            
            app.YEditField_lim.Enable = 'on';
            app.ZEditField_lim.Enable = 'on';
            app.XEditField_lim.Enable = 'on';
            
            app.YEditField_pos.Enable = 'on';
            app.ZEditField_pos.Enable = 'on';
            app.XEditField_pos.Enable = 'on';
            
            end
            Go2softhome(app)
                     
%             app.Scope.Timeout = 60; % Ensure user has 60 seconds to save scan file, longer may induce timeout error 
            % Need to move to work
            %pause and open up Continue/Cancel GUI
            disp('opening soft home gui')
            v_scan = Continue_Cancel;     %applications are matlab structures.
%             pause(1)
                        
            while true
                pause(0.2)
                if v_scan.confirm
                    %set current position to soft home position
                    app.XEditField_softhome.Value = app.XEditField_pos.Value;
                    app.YEditField_softhome.Value = app.YEditField_pos.Value;
                    app.ZEditField_softhome.Value = app.ZEditField_pos.Value;
                    
                    %check software/hardware limits
                    if xlim <= (app.XEditField_pos.Value + round(xdim/2,2))
                        uialert(uifigure,'X movement will exceed software limits. Scan cancelled','Warning')
                        return
                    end
                    
                    if ylim <= (app.YEditField_pos.Value + round(ydim/2,2))
                        uialert(uifigure,'Y movement will exceed software limits. Scan cancelled','Warning')
                        return
                    end
                    
                    if zlim <= (app.ZEditField_pos.Value + round(zdim/2,2))
                        uialert(uifigure, 'Z movement will exceed software limits. Scan Cancelled','Warning')
                        return
                    end
                    
                    break;
                end
                               
                if v_scan.cancel
                    return
                end
            end
            
            %set scope to zero
            %WFMPre:YOFf <NR3>;
            fprintf(app.Scope, 'CH1:WFMPre:YOFf 0e+0');

            % Read YOFFSET 
            app.yOffset = query(app.Scope, 'WFMPre:YOFF?');
            
            
            % Read YMULT to calculate the vertical values
            app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
          
            app.voltperdiv = query(app.Scope, 'CH1:Scale?');
            app.posvoltdisp = str2double(app.voltperdiv) * 4; %4 divisions in the positive
            
           % noisethreshold = 0.0005; %[V] 1 mv  %% determine from signal
           % app.noiseratio = noisethreshold/app.posvoltdisp; 
            app.noiseratio = 0.2;
            
            % Request 8 bit binary data on the CURVE query
            fprintf(app.Scope, 'DATA:ENCDG RIBINARY;WIDTH 1');
            
            
            switch program
                
                %changed all csvbool if statements from xdim --> xdim_str
                %and so on for y z
                
                case '3D'
                    if csvbool
                        fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                        fprintf(app.fdata, '%s, %s, %s, %s, %s, \n', program, xdim_str, ydim_str, zdim_str, res);
                        fprintf(app.fdata, 'z, x, y, max, min, waveform \n');
                        
                                          
                    end
                
                    [solution] = aquire3D(app,SH);
                    
                case '2DXY'
                     if csvbool
                         fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                         fprintf(app.fdata, '%s, %s, %s, %s \n', program, xdim_str, ydim_str, res);
                         fprintf(app.fdata, 'y, x, max, min, waveform \n');
                     end
                   
                    [solution] = aquire2DXY(app,SH);
                    
                case '2DXZ'
                    if csvbool
                    fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                    fprintf(app.fdata, '%s, %s, %s, %s \n', program, xdim_str, zdim_str, res);
                    fprintf(app.fdata, 'z, x, max, min, waveform \n');
                    end
                  
                    [solution] = aquire2DXZ(app,SH);
                    
                case '2DYZ'
                    if csvbool
                        fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                        fprintf(app.fdata, '%s, %s, %s, %s \n', program, ydim_str, zdim_str, res);
                        fprintf(app.fdata, 'max, min, waveform \n');
                    end
                    
                    [solution] = aquire2DYZ(app,SH);
                    
                case '1DX'
                    
                    if csvbool
                        fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                        fprintf(app.fdata, '%s, %s, %s \n', program, xdim_str, res);
                        fprintf(app.fdata, 'x, max, min, waveform \n');
                    end
                    
                    [solution] = aquire1DX(app);
                    
                case '1DY'
                    if csvbool
                        fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                        fprintf(app.fdata, '%s, %s, %s \n', program, ydim_str, res);
                        fprintf(app.fdata, 'x, max, min, waveform \n');
                    end
                    
                    [solution] = aquire1DY(app);
                    
                case '1DZ'
                    if csvbool
                        fprintf(app.fdata, 'Type of Scan, x, y, z, resolution \n');
                        fprintf(app.fdata, '%s, %s, %s \n', program, zdim_str, res);
                        fprintf(app.fdata, 'max, min, waveform \n');
                    end
                     
                    [solution] = aquire1DZ(app);
                    

            end


            if matrixbool
                
            solutionlocation = strcat(app.path,file);
            save(solutionlocation, 'solution')
            

            GenerateImages(app,solution);
            
            end
            toc
            SHselect = uiconfirm(app.UIFigure,...
           'Would you like to return to the soft home?',...
           'Scan Home Confirmation',...
           'Options',{'Yes','No','Cancel'},...
           'DefaultOption',2,'CancelOption',3);
            if strcmp(SHselect,'Yes')
                 
                moveinx = app.XEditField_softhome.Value - app.XEditField_pos.Value;
                xmovesteps = round(moveinx/app.mmperstep);
                x2complement(app, xmovesteps)
                
                moveiny = app.YEditField_softhome.Value - app.YEditField_pos.Value;
                ymovesteps = round(moveiny/app.mmperstep);
                y2complement(app, ymovesteps)
                
                moveinz = app.ZEditField_softhome.Value - app.ZEditField_pos.Value;
                zmovesteps = round(moveinz/app.mmperstep);
                z2complement(app, zmovesteps)
            end
           
            catch e
                fprintf(1,'The identifier was:\n%s',e.identifier);
                fprintf(1,'\nThere was an error! The message was:\n%s',e.message);
                merror.softhome = [app.XEditField_softhome.Value; app.YEditField_softhome.Value; app.ZEditField_softhome.Value];
                merror.position = [app.XEditField_pos.Value; app.YEditField_pos.Value; app.ZEditField_pos.Value];
                errorfile = sprintf('merror%s.mat', datestr(now,'mm-dd-yyyy HH-MM'));
                merrorlocation = strcat(app.path,errorfile);
                save(merrorlocation, 'merror')
            end
            
        end

        % Button pushed function: SetSoftHomeButton
        function SetSoftHomeButtonPushed(app, event)
        %sets current position as soft home location. 
            
        app.XEditField_softhome.Value = app.XEditField_pos.Value;
        app.YEditField_softhome.Value = app.YEditField_pos.Value;
        app.ZEditField_softhome.Value = app.ZEditField_pos.Value;
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            %% Function Name: CenterButtonPushed
            % Description: Finds the maximum signal along the x, y and z
            % axis. 
            
            % Scans with 0.2 resolution in the x-axis then moves to the
            % maximum signal location in x. 
            % 
            % Repeats for y and then z.  
            % 
            % Assumptions: Check that the scan dimensions won't exceed
            % software limits or hit anything!
            %
            % Input: xdim, ydim, zdim
            % Outputs: None
            %
            % Last Edited by: Sam Clinard
            % Jun 22, 2020
            % ___________________

            app.SaveWaveinMatrixSwitch.Value = 'Off';
            app.SaveWaveinMatrixSwitch.Enable = 'off';
            if strcmp(app.YEditField_pos.Enable,'off')
                  HHCselect = uiconfirm(app.UIFigure,...
                  'Would you like to Hard Home the system first?',...
                  'Scan Home Confirmation',...
                  'Options',{'Yes','No','Cancel'},...
                  'DefaultOption',1,'CancelOption',3);
                  if strcmp(HHCselect,'Yes')
                      GoHome(app)
                  end
            else
            
            app.YEditField_lim.Enable = 'on';
            app.ZEditField_lim.Enable = 'on';
            app.XEditField_lim.Enable = 'on';
            
            end
            
            
            center_confirmation = Continue_Cancel;
            pause(3)
            
            while true   
                pause(0.2)
                if center_confirmation.confirm
                    %add software limits
                    
                    break
                    
                elseif center_confirmation.cancel
                    
                    return

                end
            end
            
            close_position = [app.XEditField_pos.Value,app.YEditField_pos.Value,app.ZEditField_pos.Value];
           
            
            disp(close_position)
            xdim = app.XEditField_dim.Value; %The length of the three axes to look over in mm.  Defined in the scan dimension text boxes. 
            ydim = app.YEditField_dim.Value;
            zdim = app.ZEditField_dim.Value;    
            
                                        
            xlim = app.XEditField_lim.Value; %mm
            ylim = app.YEditField_lim.Value;
            zlim = app.ZEditField_lim.Value;                       
           
            %set up properties
  
            app.stepspervoxel = round((0.2)/app.mmperstep);  %whole steps per voxel            
            app.realresolution = app.mmperstep * app.stepspervoxel; %mm per voxel after rounding  
            
            %disp(app.stepspervoxel)
            %disp(app.realresolution)
            
            x = xdim/app.realresolution; %dimensionless (mm/mm)
            y = ydim/app.realresolution; 
            z = zdim/app.realresolution; 
                                       
            app.Xvoxels = round(x);  %round to whole number of voxels
            app.Yvoxels = round(y);
            app.Zvoxels = round(z);
            
            %disp(x)
            %disp(app.Xvoxels)
            
            if xdim ~= app.Xvoxels * 0.2
                app.XEditField_dim.Value = xdim;
                uialert(uifigure,'X dimension updated to avoid rounding errors','Warning')
            end
            
            if ydim ~= app.Yvoxels * 0.2
                app.YEditField_dim.Value = ydim;
                uialert(uifigure,'Y dimension updated to avoid rounding errors','Warning')
            end
            
            if zdim ~= app.Zvoxels * 0.2
                app.ZEditField_dim.Value = zdim;
                uialert(uifigure,'Z dimension updated to avoid rounding errors','Warning')
            end


            if xlim <= (close_position(1) + round(xdim/2,2))
                uialert(uifigure,'X movement will exceed software limits. Center search cancelled','Warning')
                return
            end
            
            if ylim <= (close_position(2) + round(ydim/2,2))
                uialert(uifigure,'Y movement will exceed software limits. Center search cancelled','Warning')
                return
            end
            
            if zlim <= (close_position(3) + round(zdim/2,2))
                uialert(uifigure, 'Z movement will exceed software limits. Center search cancelled','Warning')
                return
            end
            
          
            %set scope to zero
            %WFMPre:YOFf <NR3>
            fprintf(app.Scope, 'CH1:WFMPre:YOFf 0e+0');
            
            % Read YOFFSET 
            app.yOffset = query(app.Scope, 'WFMPre:YOFF?');
            disp('yoffset');
            disp(app.yOffset);
            
            % Read YMULT to calculate the vertical values
            app.verticalScale  = query(app.Scope,'WFMPRE:YMULT?');
          
            app.voltperdiv = query(app.Scope, 'CH1:Scale?');
            app.posvoltdisp = str2double(app.voltperdiv) * 4; %4 divisions in the positive
            
           % noisethreshold = 0.0005; %[V] 1 mv  %% determine from signal
           % app.noiseratio = noisethreshold/app.posvoltdisp; 
            app.noiseratio = 0.2;
            
            % Request 8 bit binary data on the CURVE query
            fprintf(app.Scope, 'DATA:ENCDG RIBINARY;WIDTH 1');


            % find center x and go there
            app.zerom = zeros(app.Xvoxels);
            
            [data] = aquire1DX(app);     %scan for x center - save to Solution matrix
            
            [return_mm] = center_offset(app, data);   %find x centerpoint
            disp('returnmm')
            disp(return_mm)
            app.XEditField_move.Value = (return_mm);
            
            XButton_negative(app)
            
            
            %  y axis center
            app.zerom = zeros(app.Yvoxels);
            
            [data] = aquire1DY(app);     %scan for y center - save to Solution matrix
            
            [return_mm] = center_offset(app, data);   %find y centerpoint
            
            app.YEditField_move.Value = (return_mm);
            
            YButton_negative(app)
            
            
             %  z axis center
            app.zerom = zeros(app.Zvoxels);
            
            [data] = aquire1DZ(app);     %scan for y center - save to Solution matrix
            
            [return_mm] = center_offset(app, data);   %find y centerpoint
            
            app.ZEditField_move.Value = return_mm;
            
            ZButton_negative(app)
            
            final_position = [app.XEditField_pos.Value,app.YEditField_pos.Value,app.ZEditField_pos.Value];
            
            app.XEditField_softhome.Value = final_position(1);
            app.YEditField_softhome.Value = final_position(2);
            app.ZEditField_softhome.Value = final_position(3);
            
            disp(final_position)
            app.SaveWaveinMatrixSwitch.Enable = 'on';
            
            
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.ArrivalTimeusEditField.Value = 1e6*app.RadiusofCurvaturemmEditField.Value/app.SpeedofSoundmmsEditField.Value;
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
%             Computes the signal and noise of observed waveform from the 
%             scope.
%             CH 7/1/2021
%
 try
            [waveform,~,horscale,~] = getwave(app);

             localmaxs = islocalmax(waveform(2,:),'MinSeparation',10); %Find local maxs
             % of waveforms to determine amplitudes and make sure no maxs
             % right next to eachother which leads to NaN 
             
             dt = str2double(horscale)*10/length(waveform); %us
             t = dt:dt:(dt)*length(waveform);
             
             wavedata = waveform;
             [file, app.path] = uiputfile('*.mat','File Selection','wavedata');
             app.UIFigure.Visible = 'off';
             app.UIFigure.Visible = 'on';
             solutionlocation = strcat(app.path,file);
             save(solutionlocation, 'wavedata')
             save('wavedata.mat','wavedata');
             plot(1e6*t,waveform) % convert to us
             xlabel('us')
             ylabel('mV')
             hold on
             l = 1;
             for q = 1:length(localmaxs)
                 if localmaxs(q)
                     alllm(1,l) = t(q);
                     alllm(2,l) = waveform(2,q);
                     l = l + 1;
                 end
             end
%            
             [maxval, maxidx] = max(alllm(2,:));
             thresh = 0.5*maxval;
             tolvfind = find(alllm(2,:) > thresh);
             avgsig = abs(mean(alllm(2,tolvfind)));
             avgtol = 0.5*avgsig;
             
             for i = maxidx:-1:1
%                     disp(alllm(2,i))
                    if alllm(2,i) > avgtol
                       % nothing
                    else
                        [~, nidx] = find(waveform(1,:) == alllm(1,i));
                        break
                    end    
             end
             noiseavg = abs(mean(waveform(2,1:nidx)));
             app.AverageNoisemVEditField.Value = 2*noiseavg;
             app.AverageSignalmVEditField.Value = 2*avgsig;
             app.SignaltoNoiseRatioEditField.Value = app.AverageSignalmVEditField.Value/app.AverageNoisemVEditField.Value;
 catch
     uialert(app.UIFigure, 'An unkown error occured. Please try again.','Warning');
     
 end
        end

        % Callback function
        function NewOriginSwitchValueChanged(app, event)
            value = app.NewOriginSwitch.Value;
            if value == "On"
                app.YEditField_pos.Enable = 'on';
                app.ZEditField_pos.Enable = 'on';
                app.XEditField_pos.Enable = 'on';
            
                app.YEditField_lim.Enable = 'on';
                app.ZEditField_lim.Enable = 'on';
                app.XEditField_lim.Enable = 'on';
            
                app.YEditField_pos.Value = 0;
                app.ZEditField_pos.Value = 0;
                app.XEditField_pos.Value = 0;
            end
            
        end

        % Value changed function: FWHMSwitch
        function FWHMSwitchValueChanged(app, event)
            value = app.FWHMSwitch.Value;
        end

        % Value changed function: SaveWaveinMatrixSwitch
        function SaveWaveinMatrixSwitchValueChanged(app, event)
            value = app.SaveWaveinMatrixSwitch.Value;
            
        end

        % Button pushed function: RecoverButton
        function RecoverButtonPushed(app, event)
            Rselect = uiconfirm(app.UIFigure,...
            'Sorry for the error. Would you like to recover the previous soft home?',...
            'Soft Home Recovery Confirmation',...
            'Options',{'Yes','No'},...
            'DefaultOption',1,'CancelOption',2);
            if strcmp(Rselect,'Yes')
                filefind = uiconfirm(app.UIFigure,...
                'Would you like to automatically use the last session or browse an older session?',...
                'Soft Home File Recovery',...
                'Options',{'Last','Browse...','Cancel'},...
                'DefaultOption',2,'CancelOption',3);
            if ~strcmp(filefind,'Cancel')
                cdir = cd;
                if strcmp(filefind,'Last')
                    try
                        d = dir;
                        k = 0;
                        for i = 1:length(d)
                            if contains(d(i).name,'merror') 
                                k = k + 1;
                                dt(k,1) = (now - d(i).datenum);
                                dt(k,2) = i;
                            end
                        end
                        [~,idx] = min(dt(:,1));
                        foi = dt(idx,2);
                        
                        errorfile = d(foi).name;
                    catch
                        uialert(app.UIFigure, 'Could not find recent session. Select OK to Browse...','Error')
                        filefind = 'Browse...';
                    end
                end
                if strcmp(filefind,'Browse...')
                    
                    [errorfile,errorpath] = uigetfile;
                    app.UIFigure.Visible = 'off';
                    app.UIFigure.Visible = 'on';
                end
                    app.YEditField_lim.Enable = 'on';
                    app.ZEditField_lim.Enable = 'on';
                    app.XEditField_lim.Enable = 'on';
            
                    app.YEditField_pos.Enable = 'on';
                    app.ZEditField_pos.Enable = 'on';
                    app.XEditField_pos.Enable = 'on';
                    cd(errorpath)
                    errorPos = load(errorfile);
                    PosChange = -1*(errorPos.merror.position-errorPos.merror.softhome);
                    Z = PosChange(3,1);
                    X = PosChange(1,1);
                    Y = PosChange(2,1);
                    Moveselect = uiconfirm(app.UIFigure,...
                    ['Are you sure you want to move... ', newline, num2str(X),...
                    ' mm in X,', newline, num2str(Y), ' mm in Y,',...
                    newline, 'and ', num2str(Z), ' mm in Z?'],...
                    'Move Confirmation',...
                    'Options',{'Yes','No'},...
                    'DefaultOption',1,'CancelOption',2);
            if strcmp(Moveselect,'Yes')
                    zmovesteps = round(Z/app.mmperstep);
                    z2complement(app, zmovesteps)
                    xmovesteps = round(X/app.mmperstep);
                    x2complement(app, xmovesteps)
                    ymovesteps = round(Y/app.mmperstep);
                    y2complement(app, ymovesteps)
                    
            end
            end
            cd(cdir)
            end
            
        end

        % Selection changed function: SoftHomePositionButtonGroup
        function SoftHomePositionButtonGroupSelectionChanged(app, event)
%             selectedButton = get(app.SoftHomePositionButtonGroup,"SelectedObject");
%             choice = get(selectedButton,'Text');
%             if strcmp(choice, 'Scan Corner')
%             end
        end

        % Button pushed function: NewOriginButton
        function NewOriginButtonPushed(app, event)
                app.YEditField_pos.Enable = 'on';
                app.ZEditField_pos.Enable = 'on';
                app.XEditField_pos.Enable = 'on';
            
                app.YEditField_lim.Enable = 'on';
                app.ZEditField_lim.Enable = 'on';
                app.XEditField_lim.Enable = 'on';
            
                app.YEditField_pos.Value = 0;
                app.ZEditField_pos.Value = 0;
                app.XEditField_pos.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequestFCN, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.Panel = uipanel(app.ScanTab);
            app.ProgramDropDownLabel = uilabel(app.Panel);
            app.ProgramDropDownLabel.Text = 'Program ';
            app.ProgramDropDown = uidropdown(app.Panel);
            app.ProgramDropDown.Items = {'3D', '2DXY', '2DXZ', '2DYZ', '1DX', '1DY', '1DZ'};
            app.ProgramDropDown.Value = '2DXY';
            app.ResolutionmmEditFieldLabel = uilabel(app.Panel);
            app.ResolutionmmEditFieldLabel.Text = 'Resolution [mm]';
            app.ResolutionmmEditField = uieditfield(app.Panel, 'numeric');
            app.ResolutionmmEditField.Value = 0.25;
            app.HardHomeButton = uibutton(app.Panel, 'push');
            app.HardHomeButton.ButtonPushedFcn = createCallbackFcn(app, @HardHomeButtonPushed, true);
            app.HardHomeButton.Text = 'Hard Home';
            app.InforecordedinRead_MetxtPanel = uipanel(app.Panel);
            app.InforecordedinRead_MetxtPanel.Title = 'Info recorded in Read_Me.txt';
            app.CommentsTextArea = uitextarea(app.InforecordedinRead_MetxtPanel);
            app.CommentsTextArea.Value = {'Comments Inserted here will be included in the Read_Me.txt'};
            app.DateEditFieldLabel = uilabel(app.InforecordedinRead_MetxtPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.InforecordedinRead_MetxtPanel, 'text');
            app.DateEditField.Value = '6/22/2020';
            app.TemperatureCEditFieldLabel = uilabel(app.InforecordedinRead_MetxtPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature [*C]';
            app.TemperatureCEditField = uieditfield(app.InforecordedinRead_MetxtPanel, 'numeric');
            app.TemperatureCEditField.Value = 22;
            app.HydrophoneModelEditFieldLabel = uilabel(app.InforecordedinRead_MetxtPanel);
            app.HydrophoneModelEditFieldLabel.Text = 'Hydrophone Model';
            app.HydrophoneModelEditField = uieditfield(app.InforecordedinRead_MetxtPanel, 'text');
            app.HydrophoneModelEditField.Value = 'Onda Needle';
            app.TransducerModelEditFieldLabel = uilabel(app.InforecordedinRead_MetxtPanel);
            app.TransducerModelEditFieldLabel.Text = 'Transducer Model';
            app.TransducerModelEditField = uieditfield(app.InforecordedinRead_MetxtPanel, 'text');
            app.TransducerModelEditField.Value = 'Sonic Concepts 350kHz';
            app.SaveAsDropDownLabel = uilabel(app.Panel);
            app.SaveAsDropDownLabel.Text = 'Save As';
            app.SaveAsDropDown = uidropdown(app.Panel);
            app.SaveAsDropDown.Items = {'Matrix', 'CSV', 'Matrix and CSV'};
            app.SaveAsDropDown.Value = 'Matrix';
            app.RunScanButton = uibutton(app.Panel, 'push');
            app.RunScanButton.ButtonPushedFcn = createCallbackFcn(app, @RunScanButtonPushed, true);
            app.RunScanButton.Text = 'Run Scan';
            app.ConnectionsPanel = uipanel(app.Panel);
            app.ConnectionsPanel.Title = 'Connections';
            app.ArduinoButton = uibutton(app.ConnectionsPanel, 'push');
            app.ArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ArduinoButtonPushed, true);
            app.ArduinoButton.Text = ' Arduino';
            app.Lamp = uilamp(app.ConnectionsPanel);
            app.GeneratorButton = uibutton(app.ConnectionsPanel, 'push');
            app.GeneratorButton.Text = 'Generator';
            app.Lamp2 = uilamp(app.ConnectionsPanel);
            app.OscilloscopeButton = uibutton(app.ConnectionsPanel, 'push');
            app.OscilloscopeButton.Text = 'Oscilloscope';
            app.Lamp3 = uilamp(app.ConnectionsPanel);
            app.StopButton = uibutton(app.Panel, 'state');
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.Panel, 'state');
            app.PauseButton.Text = 'Pause';
            app.PositionPanel = uipanel(app.Panel);
            app.PositionPanel.Title = 'Position';
            app.YEditFieldLabel = uilabel(app.PositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField_pos = uieditfield(app.PositionPanel, 'numeric');
            app.YEditField_pos.Editable = 'off';
            app.YEditField_pos.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.PositionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField_pos = uieditfield(app.PositionPanel, 'numeric');
            app.ZEditField_pos.Editable = 'off';
            app.ZEditField_pos.Enable = 'off';
            app.XEditFieldLabel = uilabel(app.PositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField_pos = uieditfield(app.PositionPanel, 'numeric');
            app.XEditField_pos.Editable = 'off';
            app.XEditField_pos.Enable = 'off';
            app.LimitPanel = uipanel(app.Panel);
            app.LimitPanel.Title = 'Limit';
            app.YEditField_2Label = uilabel(app.LimitPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_lim = uieditfield(app.LimitPanel, 'numeric');
            app.YEditField_lim.Enable = 'off';
            app.YEditField_lim.Value = 68;
            app.ZEditField_2Label = uilabel(app.LimitPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_lim = uieditfield(app.LimitPanel, 'numeric');
            app.ZEditField_lim.Enable = 'off';
            app.ZEditField_lim.Value = 170;
            app.XEditField_2Label = uilabel(app.LimitPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_lim = uieditfield(app.LimitPanel, 'numeric');
            app.XEditField_lim.Enable = 'off';
            app.XEditField_lim.Value = 511;
            app.MovePanel = uipanel(app.Panel);
            app.MovePanel.Title = 'Move';
            app.YEditFieldLabel_2 = uilabel(app.MovePanel);
            app.YEditFieldLabel_2.Text = 'Y';
            app.YEditField_move = uieditfield(app.MovePanel, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.MovePanel);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.ZEditField_move = uieditfield(app.MovePanel, 'numeric');
            app.XEditFieldLabel_2 = uilabel(app.MovePanel);
            app.XEditFieldLabel_2.Text = 'X';
            app.XEditField_move = uieditfield(app.MovePanel, 'numeric');
            app.XButton = uibutton(app.MovePanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.MovePanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_negative, true);
            app.XButton_2.Text = 'X-';
            app.YButton_2 = uibutton(app.MovePanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton_2.Text = 'Y+';
            app.YButton = uibutton(app.MovePanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButton_negative, true);
            app.YButton.Text = 'Y-';
            app.ZButton_2 = uibutton(app.MovePanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton_2.Text = 'Z+';
            app.ZButton = uibutton(app.MovePanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButton_negative, true);
            app.ZButton.Text = 'Z-';
            app.ScanDimensionsPanel = uipanel(app.Panel);
            app.ScanDimensionsPanel.Title = 'Scan Dimensions';
            app.YEditFieldLabel_3 = uilabel(app.ScanDimensionsPanel);
            app.YEditFieldLabel_3.Text = 'Y';
            app.YEditField_dim = uieditfield(app.ScanDimensionsPanel, 'numeric');
            app.ZEditFieldLabel_3 = uilabel(app.ScanDimensionsPanel);
            app.ZEditFieldLabel_3.Text = 'Z';
            app.ZEditField_dim = uieditfield(app.ScanDimensionsPanel, 'numeric');
            app.XEditFieldLabel_3 = uilabel(app.ScanDimensionsPanel);
            app.XEditFieldLabel_3.Text = 'X';
            app.XEditField_dim = uieditfield(app.ScanDimensionsPanel, 'numeric');
            app.SoftHomePanel = uipanel(app.Panel);
            app.SoftHomePanel.Title = 'Soft Home';
            app.YEditFieldLabel_4 = uilabel(app.SoftHomePanel);
            app.YEditFieldLabel_4.Text = 'Y';
            app.YEditField_softhome = uieditfield(app.SoftHomePanel, 'numeric');
            app.YEditField_softhome.Value = 10;
            app.ZEditFieldLabel_4 = uilabel(app.SoftHomePanel);
            app.ZEditFieldLabel_4.Text = 'Z';
            app.ZEditField_softhome = uieditfield(app.SoftHomePanel, 'numeric');
            app.ZEditField_softhome.Value = 10;
            app.XEditFieldLabel_4 = uilabel(app.SoftHomePanel);
            app.XEditFieldLabel_4.Text = 'X';
            app.XEditField_softhome = uieditfield(app.SoftHomePanel, 'numeric');
            app.XEditField_softhome.Value = 10;
            app.SetSoftHomeButton = uibutton(app.Panel, 'push');
            app.SetSoftHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SetSoftHomeButtonPushed, true);
            app.SetSoftHomeButton.Text = 'Set Soft Home';
            app.CenterButton = uibutton(app.Panel, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.FWHMSwitchLabel = uilabel(app.Panel);
            app.FWHMSwitchLabel.Text = 'FWHM';
            app.FWHMSwitch = uiswitch(app.Panel, 'slider');
            app.FWHMSwitch.ValueChangedFcn = createCallbackFcn(app, @FWHMSwitchValueChanged, true);
            app.FWHMSwitch.Value = 'On';
            app.SaveWaveinMatrixSwitchLabel = uilabel(app.Panel);
            app.SaveWaveinMatrixSwitchLabel.Text = 'Save Wave in Matrix';
            app.SaveWaveinMatrixSwitch = uiswitch(app.Panel, 'slider');
            app.SaveWaveinMatrixSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveWaveinMatrixSwitchValueChanged, true);
            app.SaveWaveinMatrixSwitch.Value = 'On';
            app.RecoverButton = uibutton(app.Panel, 'push');
            app.RecoverButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverButtonPushed, true);
            app.RecoverButton.Text = 'Recover';
            app.SoftHomePositionButtonGroup = uibuttongroup(app.Panel);
            app.SoftHomePositionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoftHomePositionButtonGroupSelectionChanged, true);
            app.SoftHomePositionButtonGroup.Title = 'Soft Home Position';
            app.ScanCornerButton = uiradiobutton(app.SoftHomePositionButtonGroup);
            app.ScanCornerButton.Text = 'Scan Corner';
            app.FocalPointButton = uiradiobutton(app.SoftHomePositionButtonGroup);
            app.FocalPointButton.Text = 'Focal Point';
            app.FocalPointButton.Value = true;
            app.NewOriginButton = uibutton(app.Panel, 'push');
            app.NewOriginButton.ButtonPushedFcn = createCallbackFcn(app, @NewOriginButtonPushed, true);
            app.NewOriginButton.Text = 'New Origin';
            app.VMPaLabel = uilabel(app.Panel);
            app.VMPaLabel.Text = '[V/MPa]';
            app.HydrophoneCalibrationValueEditFieldLabel = uilabel(app.Panel);
            app.HydrophoneCalibrationValueEditFieldLabel.Text = {'Hydrophone'; 'Calibration Value'};
            app.HydrophoneCalibrationValueEditField = uieditfield(app.Panel, 'numeric');
            app.HydrophoneCalibrationValueEditField.Value = 1;
            app.FunctionGeneratorTab = uitab(app.TabGroup);
            app.FunctionGeneratorTab.Title = 'Function Generator';
            app.FunctionDropDownLabel = uilabel(app.FunctionGeneratorTab);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.FunctionGeneratorTab);
            app.FunctionDropDown.Items = {'Sin', 'Square', 'Ramp'};
            app.FunctionDropDown.Value = 'Ramp';
            app.AmplitudeVEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude [V]';
            app.AmplitudeVEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.AmplitudeVEditField.Value = 10;
            app.FrequencyHzEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.FrequencyHzEditField.Value = 1100000;
            app.GenerateButton = uibutton(app.FunctionGeneratorTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.PhaseDegEditField_2Label = uilabel(app.FunctionGeneratorTab);
            app.PhaseDegEditField_2Label.Text = 'Phase [Deg]';
            app.PhaseDegEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.OffsetVEditField_2Label = uilabel(app.FunctionGeneratorTab);
            app.OffsetVEditField_2Label.Text = 'Offset [V]';
            app.OffsetVEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.RunModeDropDownLabel = uilabel(app.FunctionGeneratorTab);
            app.RunModeDropDownLabel.Text = 'Run Mode';
            app.RunModeDropDown = uidropdown(app.FunctionGeneratorTab);
            app.RunModeDropDown.Items = {'Continuous', 'Burst'};
            app.RunModeDropDown.Value = 'Burst';
            app.NumCyclesEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.NumCyclesEditFieldLabel.Text = 'Num Cycles';
            app.NumCyclesEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.NumCyclesEditField.Value = 5;
            app.TrigDelaymsEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.TrigDelaymsEditFieldLabel.Text = 'TrigDelay [ms]';
            app.TrigDelaymsEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.IntervalmsEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.IntervalmsEditFieldLabel.Text = 'Interval [ms]';
            app.IntervalmsEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.IntervalmsEditField.Value = 10;
            app.LoadImpedanceOhmEditFieldLabel = uilabel(app.FunctionGeneratorTab);
            app.LoadImpedanceOhmEditFieldLabel.Text = 'Load Impedance [Ohm] ';
            app.LoadImpedanceOhmEditField = uieditfield(app.FunctionGeneratorTab, 'numeric');
            app.ResetGeneratorButton = uibutton(app.FunctionGeneratorTab, 'push');
            app.ResetGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGeneratorButtonPushed, true);
            app.ResetGeneratorButton.Text = 'Reset Generator';
            app.OscilloscopeTab = uitab(app.TabGroup);
            app.OscilloscopeTab.Title = 'Oscilloscope';
            app.CouplingDropDownLabel = uilabel(app.OscilloscopeTab);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingDropDown = uidropdown(app.OscilloscopeTab);
            app.CouplingDropDown.Items = {'DC', 'AC', 'GND'};
            app.CouplingDropDown.Value = 'AC';
            app.TrigSourceDropDownLabel = uilabel(app.OscilloscopeTab);
            app.TrigSourceDropDownLabel.Text = 'Trig Source';
            app.TrigSourceDropDown = uidropdown(app.OscilloscopeTab);
            app.TrigSourceDropDown.Items = {'Ext', 'CH1', 'CH2'};
            app.TrigSourceDropDown.Value = 'Ext';
            app.AttenuationDropDownLabel = uilabel(app.OscilloscopeTab);
            app.AttenuationDropDownLabel.Text = 'Attenuation';
            app.AttenuationDropDown = uidropdown(app.OscilloscopeTab);
            app.AttenuationDropDown.Items = {'1', '10', '20', '50', '100', '1000'};
            app.AttenuationDropDown.Value = '1';
            app.AcquireModeDropDownLabel = uilabel(app.OscilloscopeTab);
            app.AcquireModeDropDownLabel.Text = 'Acquire Mode';
            app.AcquireModeDropDown = uidropdown(app.OscilloscopeTab);
            app.AcquireModeDropDown.Items = {'Sample', 'Average', 'PeakDetect'};
            app.AcquireModeDropDown.Value = 'Average';
            app.AverageDropDownLabel = uilabel(app.OscilloscopeTab);
            app.AverageDropDownLabel.Text = '# Average';
            app.AverageDropDown = uidropdown(app.OscilloscopeTab);
            app.AverageDropDown.Items = {'4', '16', '64', '128'};
            app.AverageDropDown.Value = '4';
            app.HorizontalScalesEditFieldLabel = uilabel(app.OscilloscopeTab);
            app.HorizontalScalesEditFieldLabel.Text = 'Horizontal Scale [s]';
            app.HorizontalScalesEditField = uieditfield(app.OscilloscopeTab, 'text');
            app.HorizontalScalesEditField.Value = '2.5e-6';
            app.TrigCoupleDropDownLabel = uilabel(app.OscilloscopeTab);
            app.TrigCoupleDropDownLabel.Text = 'Trig Couple';
            app.TrigCoupleDropDown = uidropdown(app.OscilloscopeTab);
            app.TrigCoupleDropDown.Items = {'DC', 'AC'};
            app.TrigCoupleDropDown.Value = 'DC';
            app.ApplyButton = uibutton(app.OscilloscopeTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SlopeDropDownLabel = uilabel(app.OscilloscopeTab);
            app.SlopeDropDownLabel.Text = 'Slope';
            app.SlopeDropDown = uidropdown(app.OscilloscopeTab);
            app.SlopeDropDown.Items = {'Rise', 'Fall'};
            app.SlopeDropDown.Value = 'Rise';
            app.VerticalScaleVEditFieldLabel = uilabel(app.OscilloscopeTab);
            app.VerticalScaleVEditFieldLabel.Text = 'Vertical Scale [V]';
            app.VerticalScaleVEditField = uieditfield(app.OscilloscopeTab, 'text');
            app.VerticalScaleVEditField.Value = '4';
            app.ResetScopeButton = uibutton(app.OscilloscopeTab, 'push');
            app.ResetScopeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScopeButtonPushed, true);
            app.ResetScopeButton.Text = 'Reset Scope';
            app.NotesTextAreaLabel = uilabel(app.OscilloscopeTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.OscilloscopeTab);
            app.NotesTextArea.Value = {'Scope connection is configured for Channel 1.'};
            app.HorizontalPositionsEditFieldLabel = uilabel(app.OscilloscopeTab);
            app.HorizontalPositionsEditFieldLabel.Text = 'Horizontal Position [s]';
            app.HorizontalPosition = uieditfield(app.OscilloscopeTab, 'text');
            app.HorizontalPosition.Value = '0';
            app.ArrivalTimeCalculatorPanel = uipanel(app.OscilloscopeTab);
            app.ArrivalTimeCalculatorPanel.Title = 'Arrival Time Calculator';
            app.ComputeButton = uibutton(app.ArrivalTimeCalculatorPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.RadiusofCurvaturemmLabel = uilabel(app.ArrivalTimeCalculatorPanel);
            app.RadiusofCurvaturemmLabel.Text = 'Radius of Curvature [mm]';
            app.RadiusofCurvaturemmEditField = uieditfield(app.ArrivalTimeCalculatorPanel, 'numeric');
            app.RadiusofCurvaturemmEditField.Value = 31.5;
            app.SpeedofSoundmmsLabel = uilabel(app.ArrivalTimeCalculatorPanel);
            app.SpeedofSoundmmsLabel.Text = 'Speed of Sound [mm/s]';
            app.SpeedofSoundmmsEditField = uieditfield(app.ArrivalTimeCalculatorPanel, 'numeric');
            app.SpeedofSoundmmsEditField.Value = 1482000;
            app.ArrivalTimeusLabel = uilabel(app.ArrivalTimeCalculatorPanel);
            app.ArrivalTimeusLabel.Text = 'Arrival Time [us]';
            app.ArrivalTimeusEditField = uieditfield(app.ArrivalTimeCalculatorPanel, 'numeric');
            app.SignaltoNoiseCalculatorPanel = uipanel(app.OscilloscopeTab);
            app.SignaltoNoiseCalculatorPanel.Title = 'Signal-to-Noise Calculator';
            app.ComputeButton_2 = uibutton(app.SignaltoNoiseCalculatorPanel, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.AverageNoisemVLabel = uilabel(app.SignaltoNoiseCalculatorPanel);
            app.AverageNoisemVLabel.Text = 'Average Noise [mV]';
            app.AverageNoisemVEditField = uieditfield(app.SignaltoNoiseCalculatorPanel, 'numeric');
            app.AverageSignalmVLabel = uilabel(app.SignaltoNoiseCalculatorPanel);
            app.AverageSignalmVLabel.Text = 'Average Signal [mV]';
            app.AverageSignalmVEditField = uieditfield(app.SignaltoNoiseCalculatorPanel, 'numeric');
            app.SignaltoNoiseRatioEditFieldLabel = uilabel(app.SignaltoNoiseCalculatorPanel);
            app.SignaltoNoiseRatioEditFieldLabel.Text = 'Signal-to-Noise Ratio';
            app.SignaltoNoiseRatioEditField = uieditfield(app.SignaltoNoiseCalculatorPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FUS_Hydrophone_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end